classdef Experiment_Parameters < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ApplyParametersButton           matlab.ui.control.Button
        RDKStimulusPanel                matlab.ui.container.Panel
        WindowAngledegDropDownLabel     matlab.ui.control.Label
        WindowAngledegDropDown          matlab.ui.control.DropDown
        DotVelocityLabel                matlab.ui.control.Label
        DotVelocitydegsecEditField      matlab.ui.control.NumericEditField
        RIGHTButton                     matlab.ui.control.StateButton
        UPButton                        matlab.ui.control.StateButton
        LEFTButton                      matlab.ui.control.StateButton
        DOWNButton                      matlab.ui.control.StateButton
        DirectionsofMotiontoIncludeonlyLRworkLabel  matlab.ui.control.Label
        ExperimentParametersPanel       matlab.ui.container.Panel
        RDKStimulusTimemsEditFieldLabel  matlab.ui.control.Label
        RDKStimulusTimemsEditField      matlab.ui.control.NumericEditField
        FixationPointOnlyTimemsEditFieldLabel  matlab.ui.control.Label
        FixationPointOnlyTimemsEditField  matlab.ui.control.NumericEditField
        DecisionTargetFixationTimemsEditFieldLabel  matlab.ui.control.Label
        DecisionTargetFixationTimemsEditField  matlab.ui.control.EditField
        IntertrialIntervalmsEditFieldLabel  matlab.ui.control.Label
        IntertrialIntervalmsEditField   matlab.ui.control.NumericEditField
        FailureTimeoutmsEditFieldLabel  matlab.ui.control.Label
        FailureTimeoutmsEditField       matlab.ui.control.EditField
        ofCatchTrialsEditFieldLabel     matlab.ui.control.Label
        ofCatchTrialsEditField          matlab.ui.control.NumericEditField
        CentralPositionsforFixationPanel  matlab.ui.container.Panel
        Button_1                        matlab.ui.control.StateButton
        Button_2                        matlab.ui.control.StateButton
        Button_3                        matlab.ui.control.StateButton
        Button_4                        matlab.ui.control.StateButton
        Button_5                        matlab.ui.control.StateButton
        Button_6                        matlab.ui.control.StateButton
        Button_7                        matlab.ui.control.StateButton
        Button_8                        matlab.ui.control.StateButton
        Button_9                        matlab.ui.control.StateButton
        FixationStimulusPanel           matlab.ui.container.Panel
        TargetAngledegDropDownLabel     matlab.ui.control.Label
        TargetAngledegDropDown          matlab.ui.control.DropDown
        RewardAngledegDropDownLabel     matlab.ui.control.Label
        RewardAngledegDropDown          matlab.ui.control.DropDown
        AuditoryStimulusPanel           matlab.ui.container.Panel
        VelocitydegsecEditFieldLabel    matlab.ui.control.Label
        VelocitydegsecEditField         matlab.ui.control.NumericEditField
        LtoRCheckBox                    matlab.ui.control.CheckBox
        RtoLCheckBox                    matlab.ui.control.CheckBox
        UtoDCheckBox                    matlab.ui.control.CheckBox
        DtoUCheckBox                    matlab.ui.control.CheckBox
        DirectionsofMotiontoIncludeOnlyLRorRLworkLabel  matlab.ui.control.Label
        AUDStartTimemsEditFieldLabel    matlab.ui.control.Label
        AUDStartTimemsEditField         matlab.ui.control.NumericEditField
        AUDEndTimemsEditFieldLabel      matlab.ui.control.Label
        AUDEndTimemsEditField           matlab.ui.control.NumericEditField
        CalcTotalAUDStimTImeButton      matlab.ui.control.Button
        StimulusCoherenceRegularTrialsPanel  matlab.ui.container.Panel
        of000TrialsEditFieldLabel       matlab.ui.control.Label
        of000TrialsEditField            matlab.ui.control.NumericEditField
        of100TrialsEditFieldLabel       matlab.ui.control.Label
        of100TrialsEditField            matlab.ui.control.NumericEditField
        of005TrialsEditFieldLabel       matlab.ui.control.Label
        of005TrialsEditField            matlab.ui.control.NumericEditField
        of010TrialsEditFieldLabel       matlab.ui.control.Label
        of010TrialsEditField            matlab.ui.control.NumericEditField
        of015TrialsEditFieldLabel       matlab.ui.control.Label
        of015TrialsEditField            matlab.ui.control.NumericEditField
        of020TrialsEditFieldLabel       matlab.ui.control.Label
        of020TrialsEditField            matlab.ui.control.NumericEditField
        of025TrialsEditFieldLabel       matlab.ui.control.Label
        of025TrialsEditField            matlab.ui.control.NumericEditField
        of035TrialsEditFieldLabel       matlab.ui.control.Label
        of035TrialsEditField            matlab.ui.control.NumericEditField
        of040TrialsEditFieldLabel       matlab.ui.control.Label
        of040TrialsEditField            matlab.ui.control.NumericEditField
        of050TrialsEditFieldLabel       matlab.ui.control.Label
        of050TrialsEditField            matlab.ui.control.NumericEditField
        of060TrialsEditFieldLabel       matlab.ui.control.Label
        of060TrialsEditField            matlab.ui.control.NumericEditField
        of080TrialsEditFieldLabel       matlab.ui.control.Label
        of080TrialsEditField            matlab.ui.control.NumericEditField
        CalculateTotalTrialsButton      matlab.ui.control.Button
        CloseFigureButton               matlab.ui.control.Button
        TotalTrialsTextAreaLabel        matlab.ui.control.Label
        TotalTrialsTextArea             matlab.ui.control.TextArea
        StartExperimentButton           matlab.ui.control.Button
        AuditoryStimulusContPanel       matlab.ui.container.Panel
        TotalAUDStimTimemsLabel         matlab.ui.control.Label
        TotalAUDStimTimemsTextArea      matlab.ui.control.TextArea
        AUDStarttoEndTImesmustnotbelongerthan2500msLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        %data_cell = cell(40,1) ; % Cell for all of GUI data input to be written to .txt file
        data_cell(50,1) = {0};  
                
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

%           app.RewardAngledegDropDown.Value = 
%           app.RDKStimulusTimemsEditField.Value = 
%           app.Button_5.Value = 
%           app.IntertrialIntervalmsEditField.Value
%           app.FixationPointOnlyTimemsEditField.Value
%           app.FailureTimeoutmsEditField.Value
%           app.WindowAngledegDropDown.Value
%           app.DecisionTargetFixationTimemsEditField.Value
%           app.DotVelocitydegsecEditField.Value
%           app.LEFTButton.Value
%           app.RIGHTButton.Value
%           app.AUDStartTimemsEditField.Value
%           app.VelocitydegsecEditField.Value
%           app.LtoRCheckBox.Value
%           app.RtoLCheckBox.Value
          
        end

        % Value changed function: TargetAngledegDropDown
        function TargetAngledegDropDownValueChanged(app, event)
            t_angle = app.TargetAngledegDropDown.Value;
            t_angle = str2double(t_angle);
            app.data_cell{1,1} = t_angle; 
        end

        % Value changed function: RewardAngledegDropDown
        function RewardAngledegDropDownValueChanged(app, event)
            w_angle = app.RewardAngledegDropDown.Value;
            w_angle = str2double(w_angle);
            app.data_cell{2,1} = w_angle;
        end

        % Button pushed function: ApplyParametersButton
        function ApplyParametersButtonPushed(app, event)

            writecell(app.data_cell,"data_cell.txt")
            disp("Data Written to File")
        end

        % Value changed function: RDKStimulusTimemsEditField
        function RDKStimulusTimemsEditFieldValueChanged(app, event)
            app.data_cell{4,1} = app.RDKStimulusTimemsEditField.Value;
            
        end

        % Value changed function: Button_1
        function Button_1_1ValueChanged(app, event)
            app.data_cell{7,1} = app.Button_1.Value;
            
        end

        % Value changed function: Button_2
        function Button_2ValueChanged(app, event)
           app.data_cell{8,1} = app.Button_2.Value;
            
        end

        % Value changed function: Button_3
        function Button_3ValueChanged(app, event)
            app.data_cell{9,1} = app.Button_3.Value;
            
        end

        % Value changed function: Button_4
        function Button_4ValueChanged(app, event)
            app.data_cell{10,1} = app.Button_4.Value;
            
        end

        % Value changed function: Button_5
        function Button_5ValueChanged(app, event)
            app.data_cell{11,1} = app.Button_5.Value;
            
        end

        % Value changed function: Button_6
        function Button_6ValueChanged(app, event)
            app.data_cell{12,1} = app.Button_6.Value;
            
        end

        % Value changed function: Button_7
        function Button_7ValueChanged(app, event)
            app.data_cell{13,1} = app.Button_7.Value;
            
        end

        % Value changed function: Button_8
        function Button_8ValueChanged(app, event)
            app.data_cell{14,1} = app.Button_8.Value;
            
        end

        % Value changed function: Button_9
        function Button_9ValueChanged(app, event)
            app.data_cell{15,1} = app.Button_9.Value;
            
        end

        % Value changed function: IntertrialIntervalmsEditField
        function IntertrialIntervalmsEditFieldValueChanged(app, event)
            app.data_cell{5,1} = app.IntertrialIntervalmsEditField.Value;
            
        end

        % Value changed function: FixationPointOnlyTimemsEditField
        function FixationPointOnlyTimemsEditFieldValueChanged(app, event)
            app.data_cell{6,1} = app.FixationPointOnlyTimemsEditField.Value;
            
        end

        % Value changed function: FailureTimeoutmsEditField
        function FailureTimeoutmsEditFieldValueChanged(app, event)
            app.data_cell{16,1} = app.FailureTimeoutmsEditField.Value;
            
        end

        % Value changed function: WindowAngledegDropDown
        function WindowAngledegDropDownValueChanged(app, event)
            rdk_angle = app.WindowAngledegDropDown.Value;
            rdk_angle = str2double(rdk_angle);
            app.data_cell{17,1} = rdk_angle;
        end

        % Value changed function: 
        % DecisionTargetFixationTimemsEditField
        function DecisionTargetFixationTimemsEditFieldValueChanged(app, event)
            app.data_cell{18,1} = app.DecisionTargetFixationTimemsEditField.Value;
        end

        % Value changed function: DotVelocitydegsecEditField
        function DotVelocitydegsecEditFieldValueChanged(app, event)
             app.data_cell{20,1} = app.DotVelocitydegsecEditField.Value;
            
        end

        % Value changed function: DOWNButton
        function DOWNButtonValueChanged(app, event)
            app.data_cell{21,1} = app.DOWNButton.Value;
            
        end

        % Value changed function: LEFTButton
        function LEFTButtonValueChanged(app, event)
            app.data_cell{22,1} = app.LEFTButton.Value;
            
        end

        % Value changed function: UPButton
        function UPButtonValueChanged(app, event)
            app.data_cell{23,1} = app.UPButton.Value;
            
        end

        % Value changed function: RIGHTButton
        function RIGHTButtonValueChanged(app, event)
            app.data_cell{24,1} = app.RIGHTButton.Value;
            
        end

        % Value changed function: AUDStartTimemsEditField
        function AUDStartTimemsEditFieldValueChanged(app, event)
            app.data_cell{27,1} = app.AUDStartTimemsEditField.Value;
            
        end

        % Value changed function: VelocitydegsecEditField
        function VelocitydegsecEditFieldValueChanged(app, event)
            app.data_cell{29,1} = app.VelocitydegsecEditField.Value;
            
        end

        % Value changed function: LtoRCheckBox
        function LtoRCheckBoxValueChanged(app, event)
            app.data_cell{30,1} = app.LtoRCheckBox.Value;
            
        end

        % Value changed function: RtoLCheckBox
        function RtoLCheckBoxValueChanged(app, event)
            app.data_cell{33,1} = app.RtoLCheckBox.Value;
            
        end

        % Value changed function: UtoDCheckBox
        function UtoDCheckBoxValueChanged(app, event)
            app.data_cell{32,1} = app.UtoDCheckBox.Value;
            
        end

        % Value changed function: DtoUCheckBox
        function DtoUCheckBoxValueChanged(app, event)
            app.data_cell{31,1} = app.DtoUCheckBox.Value;
            
        end

        % Value changed function: ofCatchTrialsEditField
        function ofCatchTrialsEditFieldValueChanged(app, event)
            app.data_cell{34,1} = app.ofCatchTrialsEditField.Value;
            
        end

        % Button pushed function: CalculateTotalTrialsButton
        function CalculateTotalTrialsButtonPushed(app, event)
            %Add all trials and then assign to data(3,1)
           total = sum(cell2mat(app.data_cell(35:46,1)))+app.data_cell{34,1}; %Add regular and catch to get total
           app.data_cell{3,1} = total; 
           app.TotalTrialsTextArea.Value = num2str(total);
            
        end

        % Button pushed function: CloseFigureButton
        function CloseFigureButtonPushed(app, event)
            close(app.UIFigure);
        end

        % Value changed function: of000TrialsEditField
        function of000TrialsEditFieldValueChanged(app, event)
            app.data_cell{35,1} = app.of000TrialsEditField.Value;
            
        end

        % Value changed function: of005TrialsEditField
        function of005TrialsEditFieldValueChanged(app, event)
           app.data_cell{36,1} = app.of005TrialsEditField.Value;
            
        end

        % Value changed function: of010TrialsEditField
        function of010TrialsEditFieldValueChanged(app, event)
            app.data_cell{37,1}= app.of010TrialsEditField.Value;
            
        end

        % Value changed function: of015TrialsEditField
        function of015TrialsEditFieldValueChanged(app, event)
            app.data_cell{38,1} = app.of015TrialsEditField.Value;
            
        end

        % Value changed function: of020TrialsEditField
        function of020TrialsEditFieldValueChanged(app, event)
            app.data_cell{39,1} = app.of020TrialsEditField.Value;
            
        end

        % Value changed function: of025TrialsEditField
        function of025TrialsEditFieldValueChanged(app, event)
            app.data_cell{40,1} = app.of025TrialsEditField.Value;
            
        end

        % Value changed function: of035TrialsEditField
        function of035TrialsEditFieldValueChanged(app, event)
            app.data_cell{41,1} = app.of035TrialsEditField.Value;
            
        end

        % Value changed function: of040TrialsEditField
        function of040TrialsEditFieldValueChanged(app, event)
            app.data_cell{42,1} = app.of040TrialsEditField.Value;
            
        end

        % Value changed function: of050TrialsEditField
        function of050TrialsEditFieldValueChanged(app, event)
            app.data_cell{43,1} = app.of050TrialsEditField.Value;
            
        end

        % Value changed function: of060TrialsEditField
        function of060TrialsEditFieldValueChanged(app, event)
            app.data_cell{44,1} = app.of060TrialsEditField.Value;
            
        end

        % Value changed function: of080TrialsEditField
        function of080TrialsEditFieldValueChanged(app, event)
            app.data_cell{45,1} = app.of080TrialsEditField.Value;
            
        end

        % Value changed function: of100TrialsEditField
        function of100TrialsEditFieldValueChanged(app, event)
            app.data_cell{46,1} = app.of100TrialsEditField.Value;
            
        end

        % Button pushed function: StartExperimentButton
        function StartExperimentButtonPushed(app, event)
            app.StartExperimentButton.UserData = 1; 
        end

        % Value changed function: AUDEndTimemsEditField
        function AUDEndTimemsEditFieldValueChanged(app, event)
            app.data_cell{28,1} = app.AUDEndTimemsEditField.Value;
            
        end

        % Button pushed function: CalcTotalAUDStimTImeButton
        function CalcTotalAUDStimTImeButtonPushed(app, event)
           total_time = (app.data_cell{28,1} - app.data_cell{27,1}); %End Time - Start Time        
           app.TotalAUDStimTimemsTextArea.Value = num2str(total_time);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ApplyParametersButton = uibutton(app.UIFigure, 'push');
            app.ApplyParametersButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyParametersButtonPushed, true);
            app.ApplyParametersButton.Text = 'Apply Parameters';
            app.RDKStimulusPanel = uipanel(app.UIFigure);
            app.RDKStimulusPanel.Title = 'RDK Stimulus';
            app.WindowAngledegDropDownLabel = uilabel(app.RDKStimulusPanel);
            app.WindowAngledegDropDownLabel.Text = 'Window Angle (deg)';
            app.WindowAngledegDropDown = uidropdown(app.RDKStimulusPanel);
            app.WindowAngledegDropDown.Items = {'0', '25', '22', '20', '17', '15', '12.5', '10', '7.5', '5', '2.5'};
            app.WindowAngledegDropDown.ItemsData = {'0', '25', '22', '20', '17', '15', '12.5', '10', '7.5', '5', '2.5'};
            app.WindowAngledegDropDown.ValueChangedFcn = createCallbackFcn(app, @WindowAngledegDropDownValueChanged, true);
            app.WindowAngledegDropDown.Value = '0';
            app.DotVelocityLabel = uilabel(app.RDKStimulusPanel);
            app.DotVelocityLabel.Text = 'Dot Velocity (deg/sec)';
            app.DotVelocitydegsecEditField = uieditfield(app.RDKStimulusPanel, 'numeric');
            app.DotVelocitydegsecEditField.ValueChangedFcn = createCallbackFcn(app, @DotVelocitydegsecEditFieldValueChanged, true);
            app.RIGHTButton = uibutton(app.RDKStimulusPanel, 'state');
            app.RIGHTButton.ValueChangedFcn = createCallbackFcn(app, @RIGHTButtonValueChanged, true);
            app.RIGHTButton.Text = 'RIGHT';
            app.UPButton = uibutton(app.RDKStimulusPanel, 'state');
            app.UPButton.ValueChangedFcn = createCallbackFcn(app, @UPButtonValueChanged, true);
            app.UPButton.Text = 'UP';
            app.LEFTButton = uibutton(app.RDKStimulusPanel, 'state');
            app.LEFTButton.ValueChangedFcn = createCallbackFcn(app, @LEFTButtonValueChanged, true);
            app.LEFTButton.Text = 'LEFT';
            app.DOWNButton = uibutton(app.RDKStimulusPanel, 'state');
            app.DOWNButton.ValueChangedFcn = createCallbackFcn(app, @DOWNButtonValueChanged, true);
            app.DOWNButton.Text = 'DOWN';
            app.DirectionsofMotiontoIncludeonlyLRworkLabel = uilabel(app.RDKStimulusPanel);
            app.DirectionsofMotiontoIncludeonlyLRworkLabel.Text = {'Directions of Motion to '; 'Include (only L & R work)'};
            app.ExperimentParametersPanel = uipanel(app.UIFigure);
            app.ExperimentParametersPanel.Title = 'Experiment Parameters';
            app.RDKStimulusTimemsEditFieldLabel = uilabel(app.ExperimentParametersPanel);
            app.RDKStimulusTimemsEditFieldLabel.Text = 'RDK  Stimulus Time (ms)';
            app.RDKStimulusTimemsEditField = uieditfield(app.ExperimentParametersPanel, 'numeric');
            app.RDKStimulusTimemsEditField.ValueChangedFcn = createCallbackFcn(app, @RDKStimulusTimemsEditFieldValueChanged, true);
            app.FixationPointOnlyTimemsEditFieldLabel = uilabel(app.ExperimentParametersPanel);
            app.FixationPointOnlyTimemsEditFieldLabel.Text = 'Fixation Point Only Time (ms)';
            app.FixationPointOnlyTimemsEditField = uieditfield(app.ExperimentParametersPanel, 'numeric');
            app.FixationPointOnlyTimemsEditField.ValueChangedFcn = createCallbackFcn(app, @FixationPointOnlyTimemsEditFieldValueChanged, true);
            app.DecisionTargetFixationTimemsEditFieldLabel = uilabel(app.ExperimentParametersPanel);
            app.DecisionTargetFixationTimemsEditFieldLabel.Text = 'Decision Target FixationTime (ms)';
            app.DecisionTargetFixationTimemsEditField = uieditfield(app.ExperimentParametersPanel, 'text');
            app.DecisionTargetFixationTimemsEditField.ValueChangedFcn = createCallbackFcn(app, @DecisionTargetFixationTimemsEditFieldValueChanged, true);
            app.DecisionTargetFixationTimemsEditField.Value = '0';
            app.IntertrialIntervalmsEditFieldLabel = uilabel(app.ExperimentParametersPanel);
            app.IntertrialIntervalmsEditFieldLabel.Text = 'Intertrial Interval (ms)';
            app.IntertrialIntervalmsEditField = uieditfield(app.ExperimentParametersPanel, 'numeric');
            app.IntertrialIntervalmsEditField.ValueChangedFcn = createCallbackFcn(app, @IntertrialIntervalmsEditFieldValueChanged, true);
            app.FailureTimeoutmsEditFieldLabel = uilabel(app.ExperimentParametersPanel);
            app.FailureTimeoutmsEditFieldLabel.Text = 'Failure Timeout (ms)';
            app.FailureTimeoutmsEditField = uieditfield(app.ExperimentParametersPanel, 'text');
            app.FailureTimeoutmsEditField.ValueChangedFcn = createCallbackFcn(app, @FailureTimeoutmsEditFieldValueChanged, true);
            app.FailureTimeoutmsEditField.Value = '0';
            app.ofCatchTrialsEditFieldLabel = uilabel(app.ExperimentParametersPanel);
            app.ofCatchTrialsEditFieldLabel.Text = '# of Catch Trials ';
            app.ofCatchTrialsEditField = uieditfield(app.ExperimentParametersPanel, 'numeric');
            app.ofCatchTrialsEditField.ValueChangedFcn = createCallbackFcn(app, @ofCatchTrialsEditFieldValueChanged, true);
            app.CentralPositionsforFixationPanel = uipanel(app.UIFigure);
            app.CentralPositionsforFixationPanel.Title = 'Central Positions for Fixation ';
            app.Button_1 = uibutton(app.CentralPositionsforFixationPanel, 'state');
            app.Button_1.ValueChangedFcn = createCallbackFcn(app, @Button_1_1ValueChanged, true);
            app.Button_1.Text = '1';
            app.Button_2 = uibutton(app.CentralPositionsforFixationPanel, 'state');
            app.Button_2.ValueChangedFcn = createCallbackFcn(app, @Button_2ValueChanged, true);
            app.Button_2.Text = '2';
            app.Button_3 = uibutton(app.CentralPositionsforFixationPanel, 'state');
            app.Button_3.ValueChangedFcn = createCallbackFcn(app, @Button_3ValueChanged, true);
            app.Button_3.Text = '3';
            app.Button_4 = uibutton(app.CentralPositionsforFixationPanel, 'state');
            app.Button_4.ValueChangedFcn = createCallbackFcn(app, @Button_4ValueChanged, true);
            app.Button_4.Text = '4';
            app.Button_5 = uibutton(app.CentralPositionsforFixationPanel, 'state');
            app.Button_5.ValueChangedFcn = createCallbackFcn(app, @Button_5ValueChanged, true);
            app.Button_5.Text = '5';
            app.Button_6 = uibutton(app.CentralPositionsforFixationPanel, 'state');
            app.Button_6.ValueChangedFcn = createCallbackFcn(app, @Button_6ValueChanged, true);
            app.Button_6.Text = '6';
            app.Button_7 = uibutton(app.CentralPositionsforFixationPanel, 'state');
            app.Button_7.ValueChangedFcn = createCallbackFcn(app, @Button_7ValueChanged, true);
            app.Button_7.Text = '7';
            app.Button_8 = uibutton(app.CentralPositionsforFixationPanel, 'state');
            app.Button_8.ValueChangedFcn = createCallbackFcn(app, @Button_8ValueChanged, true);
            app.Button_8.Text = '8';
            app.Button_9 = uibutton(app.CentralPositionsforFixationPanel, 'state');
            app.Button_9.ValueChangedFcn = createCallbackFcn(app, @Button_9ValueChanged, true);
            app.Button_9.Text = '9';
            app.FixationStimulusPanel = uipanel(app.UIFigure);
            app.FixationStimulusPanel.Title = 'Fixation Stimulus';
            app.TargetAngledegDropDownLabel = uilabel(app.FixationStimulusPanel);
            app.TargetAngledegDropDownLabel.Text = 'Target Angle (deg)';
            app.TargetAngledegDropDown = uidropdown(app.FixationStimulusPanel);
            app.TargetAngledegDropDown.Items = {'0', '.1', '.2', '.3', '.4', '.5', '.6', '.7', '.8', '.9', '1', '1.1', '1.2', '1.3', '1.4', '1.5', '1.6', '1.7', ''};
            app.TargetAngledegDropDown.ItemsData = {'0', '.1', '.2', '.3', '.4', '.5', '.6', '.7', '.8', '.9', '1', '1.1', '1.2', '1.3', '1.4', '1.5', '1.6', '1.7', ''};
            app.TargetAngledegDropDown.ValueChangedFcn = createCallbackFcn(app, @TargetAngledegDropDownValueChanged, true);
            app.TargetAngledegDropDown.Value = '0';
            app.RewardAngledegDropDownLabel = uilabel(app.FixationStimulusPanel);
            app.RewardAngledegDropDownLabel.Text = 'Reward Angle (deg)';
            app.RewardAngledegDropDown = uidropdown(app.FixationStimulusPanel);
            app.RewardAngledegDropDown.Items = {'0', '30', '27', '26', '25', '23', '22', '20', '17', '15', '12.5', '10', '7.5', '5', '2.5'};
            app.RewardAngledegDropDown.ItemsData = {'0', '30', '27', '26', '25', '23', '22', '20', '17', '15', '12.5', '10', '7.5', '5', '2.5'};
            app.RewardAngledegDropDown.ValueChangedFcn = createCallbackFcn(app, @RewardAngledegDropDownValueChanged, true);
            app.RewardAngledegDropDown.Value = '0';
            app.AuditoryStimulusPanel = uipanel(app.UIFigure);
            app.AuditoryStimulusPanel.Title = 'Auditory Stimulus ';
            app.VelocitydegsecEditFieldLabel = uilabel(app.AuditoryStimulusPanel);
            app.VelocitydegsecEditFieldLabel.Text = 'Velocity (deg/sec)';
            app.VelocitydegsecEditField = uieditfield(app.AuditoryStimulusPanel, 'numeric');
            app.VelocitydegsecEditField.ValueChangedFcn = createCallbackFcn(app, @VelocitydegsecEditFieldValueChanged, true);
            app.LtoRCheckBox = uicheckbox(app.AuditoryStimulusPanel);
            app.LtoRCheckBox.ValueChangedFcn = createCallbackFcn(app, @LtoRCheckBoxValueChanged, true);
            app.LtoRCheckBox.Text = 'L to R';
            app.RtoLCheckBox = uicheckbox(app.AuditoryStimulusPanel);
            app.RtoLCheckBox.ValueChangedFcn = createCallbackFcn(app, @RtoLCheckBoxValueChanged, true);
            app.RtoLCheckBox.Text = 'R to L';
            app.UtoDCheckBox = uicheckbox(app.AuditoryStimulusPanel);
            app.UtoDCheckBox.ValueChangedFcn = createCallbackFcn(app, @UtoDCheckBoxValueChanged, true);
            app.UtoDCheckBox.Text = 'U to D';
            app.DtoUCheckBox = uicheckbox(app.AuditoryStimulusPanel);
            app.DtoUCheckBox.ValueChangedFcn = createCallbackFcn(app, @DtoUCheckBoxValueChanged, true);
            app.DtoUCheckBox.Text = 'D to U';
            app.DirectionsofMotiontoIncludeOnlyLRorRLworkLabel = uilabel(app.AuditoryStimulusPanel);
            app.DirectionsofMotiontoIncludeOnlyLRorRLworkLabel.Text = {'Directions of Motion to'; ' Include (Only LR or RL work)'};
            app.AUDStartTimemsEditFieldLabel = uilabel(app.AuditoryStimulusPanel);
            app.AUDStartTimemsEditFieldLabel.Text = 'AUD Start Time (ms)';
            app.AUDStartTimemsEditField = uieditfield(app.AuditoryStimulusPanel, 'numeric');
            app.AUDStartTimemsEditField.ValueChangedFcn = createCallbackFcn(app, @AUDStartTimemsEditFieldValueChanged, true);
            app.AUDEndTimemsEditFieldLabel = uilabel(app.AuditoryStimulusPanel);
            app.AUDEndTimemsEditFieldLabel.Text = 'AUD End Time (ms)';
            app.AUDEndTimemsEditField = uieditfield(app.AuditoryStimulusPanel, 'numeric');
            app.AUDEndTimemsEditField.ValueChangedFcn = createCallbackFcn(app, @AUDEndTimemsEditFieldValueChanged, true);
            app.CalcTotalAUDStimTImeButton = uibutton(app.AuditoryStimulusPanel, 'push');
            app.CalcTotalAUDStimTImeButton.ButtonPushedFcn = createCallbackFcn(app, @CalcTotalAUDStimTImeButtonPushed, true);
            app.CalcTotalAUDStimTImeButton.Text = 'Calc. Total AUD Stim TIme';
            app.StimulusCoherenceRegularTrialsPanel = uipanel(app.UIFigure);
            app.StimulusCoherenceRegularTrialsPanel.Title = 'Stimulus Coherence (RegularTrials)';
            app.of000TrialsEditFieldLabel = uilabel(app.StimulusCoherenceRegularTrialsPanel);
            app.of000TrialsEditFieldLabel.Text = '# of 0.00 Trials';
            app.of000TrialsEditField = uieditfield(app.StimulusCoherenceRegularTrialsPanel, 'numeric');
            app.of000TrialsEditField.ValueChangedFcn = createCallbackFcn(app, @of000TrialsEditFieldValueChanged, true);
            app.of100TrialsEditFieldLabel = uilabel(app.StimulusCoherenceRegularTrialsPanel);
            app.of100TrialsEditFieldLabel.Text = '# of 1.00 Trials';
            app.of100TrialsEditField = uieditfield(app.StimulusCoherenceRegularTrialsPanel, 'numeric');
            app.of100TrialsEditField.ValueChangedFcn = createCallbackFcn(app, @of100TrialsEditFieldValueChanged, true);
            app.of005TrialsEditFieldLabel = uilabel(app.StimulusCoherenceRegularTrialsPanel);
            app.of005TrialsEditFieldLabel.Text = '# of 0.05 Trials';
            app.of005TrialsEditField = uieditfield(app.StimulusCoherenceRegularTrialsPanel, 'numeric');
            app.of005TrialsEditField.ValueChangedFcn = createCallbackFcn(app, @of005TrialsEditFieldValueChanged, true);
            app.of010TrialsEditFieldLabel = uilabel(app.StimulusCoherenceRegularTrialsPanel);
            app.of010TrialsEditFieldLabel.Text = '# of 0.10 Trials';
            app.of010TrialsEditField = uieditfield(app.StimulusCoherenceRegularTrialsPanel, 'numeric');
            app.of010TrialsEditField.ValueChangedFcn = createCallbackFcn(app, @of010TrialsEditFieldValueChanged, true);
            app.of015TrialsEditFieldLabel = uilabel(app.StimulusCoherenceRegularTrialsPanel);
            app.of015TrialsEditFieldLabel.Text = '# of 0.15 Trials';
            app.of015TrialsEditField = uieditfield(app.StimulusCoherenceRegularTrialsPanel, 'numeric');
            app.of015TrialsEditField.ValueChangedFcn = createCallbackFcn(app, @of015TrialsEditFieldValueChanged, true);
            app.of020TrialsEditFieldLabel = uilabel(app.StimulusCoherenceRegularTrialsPanel);
            app.of020TrialsEditFieldLabel.Text = '# of 0.20 Trials';
            app.of020TrialsEditField = uieditfield(app.StimulusCoherenceRegularTrialsPanel, 'numeric');
            app.of020TrialsEditField.ValueChangedFcn = createCallbackFcn(app, @of020TrialsEditFieldValueChanged, true);
            app.of025TrialsEditFieldLabel = uilabel(app.StimulusCoherenceRegularTrialsPanel);
            app.of025TrialsEditFieldLabel.Text = '# of 0.25 Trials';
            app.of025TrialsEditField = uieditfield(app.StimulusCoherenceRegularTrialsPanel, 'numeric');
            app.of025TrialsEditField.ValueChangedFcn = createCallbackFcn(app, @of025TrialsEditFieldValueChanged, true);
            app.of035TrialsEditFieldLabel = uilabel(app.StimulusCoherenceRegularTrialsPanel);
            app.of035TrialsEditFieldLabel.Text = '# of 0.35 Trials';
            app.of035TrialsEditField = uieditfield(app.StimulusCoherenceRegularTrialsPanel, 'numeric');
            app.of035TrialsEditField.ValueChangedFcn = createCallbackFcn(app, @of035TrialsEditFieldValueChanged, true);
            app.of040TrialsEditFieldLabel = uilabel(app.StimulusCoherenceRegularTrialsPanel);
            app.of040TrialsEditFieldLabel.Text = '# of 0.40 Trials';
            app.of040TrialsEditField = uieditfield(app.StimulusCoherenceRegularTrialsPanel, 'numeric');
            app.of040TrialsEditField.ValueChangedFcn = createCallbackFcn(app, @of040TrialsEditFieldValueChanged, true);
            app.of050TrialsEditFieldLabel = uilabel(app.StimulusCoherenceRegularTrialsPanel);
            app.of050TrialsEditFieldLabel.Text = '# of 0.50 Trials';
            app.of050TrialsEditField = uieditfield(app.StimulusCoherenceRegularTrialsPanel, 'numeric');
            app.of050TrialsEditField.ValueChangedFcn = createCallbackFcn(app, @of050TrialsEditFieldValueChanged, true);
            app.of060TrialsEditFieldLabel = uilabel(app.StimulusCoherenceRegularTrialsPanel);
            app.of060TrialsEditFieldLabel.Text = '# of 0.60 Trials';
            app.of060TrialsEditField = uieditfield(app.StimulusCoherenceRegularTrialsPanel, 'numeric');
            app.of060TrialsEditField.ValueChangedFcn = createCallbackFcn(app, @of060TrialsEditFieldValueChanged, true);
            app.of080TrialsEditFieldLabel = uilabel(app.StimulusCoherenceRegularTrialsPanel);
            app.of080TrialsEditFieldLabel.Text = '# of 0.80 Trials';
            app.of080TrialsEditField = uieditfield(app.StimulusCoherenceRegularTrialsPanel, 'numeric');
            app.of080TrialsEditField.ValueChangedFcn = createCallbackFcn(app, @of080TrialsEditFieldValueChanged, true);
            app.CalculateTotalTrialsButton = uibutton(app.UIFigure, 'push');
            app.CalculateTotalTrialsButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateTotalTrialsButtonPushed, true);
            app.CalculateTotalTrialsButton.Text = 'Calculate Total Trials';
            app.CloseFigureButton = uibutton(app.UIFigure, 'push');
            app.CloseFigureButton.ButtonPushedFcn = createCallbackFcn(app, @CloseFigureButtonPushed, true);
            app.CloseFigureButton.Text = 'Close Figure';
            app.TotalTrialsTextAreaLabel = uilabel(app.UIFigure);
            app.TotalTrialsTextAreaLabel.Text = 'Total Trials';
            app.TotalTrialsTextArea = uitextarea(app.UIFigure);
            app.StartExperimentButton = uibutton(app.UIFigure, 'push');
            app.StartExperimentButton.ButtonPushedFcn = createCallbackFcn(app, @StartExperimentButtonPushed, true);
            app.StartExperimentButton.Text = 'Start Experiment';
            app.AuditoryStimulusContPanel = uipanel(app.UIFigure);
            app.AuditoryStimulusContPanel.Title = 'Auditory Stimulus Cont. ';
            app.TotalAUDStimTimemsLabel = uilabel(app.AuditoryStimulusContPanel);
            app.TotalAUDStimTimemsLabel.Text = 'Total AUD Stim Time (ms)';
            app.TotalAUDStimTimemsTextArea = uitextarea(app.AuditoryStimulusContPanel);
            app.AUDStarttoEndTImesmustnotbelongerthan2500msLabel = uilabel(app.AuditoryStimulusContPanel);
            app.AUDStarttoEndTImesmustnotbelongerthan2500msLabel.Text = {'AUD Start to End Times must '; 'be within values of 0 to 2523, and total time must not '; 'exceed 2523 ms, Make sure your matching'; ' this to RDK Stim Time'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Experiment_Parameters

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end