classdef MCS_Experiment_Parameters < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        AuditoryStimulusContPanel       matlab.ui.container.Panel
        set_durtimetotalavailmsTextArea  matlab.ui.control.TextArea
        set_durtimetotalavailmsTextAreaLabel  matlab.ui.control.Label
        AUDStarttoEndTImesmustnotbelongerthan2500msLabel  matlab.ui.control.Label
        TotalAUDStimTimemsTextArea      matlab.ui.control.TextArea
        TotalAUDStimTimemsLabel         matlab.ui.control.Label
        CloseFigureButton               matlab.ui.control.Button
        StimulusCoherenceRegularTrialsPanel  matlab.ui.container.Panel
        TotalTrialsTextArea             matlab.ui.control.TextArea
        TotalTrialsTextAreaLabel        matlab.ui.control.Label
        CalcTotalTrialsButton           matlab.ui.control.Button
        NumberofTrialsforEachcoherenceLevelLabel  matlab.ui.control.Label
        EditField_11                    matlab.ui.control.NumericEditField
        Label_11                        matlab.ui.control.Label
        EditField_10                    matlab.ui.control.NumericEditField
        Label_10                        matlab.ui.control.Label
        EditField_9                     matlab.ui.control.NumericEditField
        Label_9                         matlab.ui.control.Label
        EditField_8                     matlab.ui.control.NumericEditField
        Label_8                         matlab.ui.control.Label
        EditField_7                     matlab.ui.control.NumericEditField
        Label_7                         matlab.ui.control.Label
        EditField_6                     matlab.ui.control.NumericEditField
        Label_6                         matlab.ui.control.Label
        EditField_5                     matlab.ui.control.NumericEditField
        Label_5                         matlab.ui.control.Label
        EditField_4                     matlab.ui.control.NumericEditField
        Label_4                         matlab.ui.control.Label
        EditField_3                     matlab.ui.control.NumericEditField
        Label_3                         matlab.ui.control.Label
        EditField_2                     matlab.ui.control.NumericEditField
        Label_2                         matlab.ui.control.Label
        EditField_1                     matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        AuditoryStimulusPanel           matlab.ui.container.Panel
        CalcTotalset_durTimeButton      matlab.ui.control.Button
        CalcTotalAUDStimTImeButton      matlab.ui.control.Button
        AUDEndTimemsEditField           matlab.ui.control.NumericEditField
        AUDEndTimemsEditFieldLabel      matlab.ui.control.Label
        AUDStartTimemsEditField         matlab.ui.control.NumericEditField
        AUDStartTimemsEditFieldLabel    matlab.ui.control.Label
        DtoUCheckBox                    matlab.ui.control.CheckBox
        UtoDCheckBox                    matlab.ui.control.CheckBox
        RtoLCheckBox                    matlab.ui.control.CheckBox
        LtoRCheckBox                    matlab.ui.control.CheckBox
        VelocitydegsecEditField         matlab.ui.control.NumericEditField
        VelocitydegsecEditFieldLabel    matlab.ui.control.Label
        FixationStimulusPanel           matlab.ui.container.Panel
        RewardAngledegDropDown          matlab.ui.control.DropDown
        RewardAngledegDropDownLabel     matlab.ui.control.Label
        TargetAngledegDropDown          matlab.ui.control.DropDown
        TargetAngledegDropDownLabel     matlab.ui.control.Label
        CentralPositionsforFixationPanel  matlab.ui.container.Panel
        Button_9                        matlab.ui.control.StateButton
        Button_8                        matlab.ui.control.StateButton
        Button_7                        matlab.ui.control.StateButton
        Button_6                        matlab.ui.control.StateButton
        Button_5                        matlab.ui.control.StateButton
        Button_4                        matlab.ui.control.StateButton
        Button_3                        matlab.ui.control.StateButton
        Button_2                        matlab.ui.control.StateButton
        Button_1                        matlab.ui.control.StateButton
        ExperimentParametersPanel       matlab.ui.container.Panel
        FailureTimeoutmsEditField       matlab.ui.control.EditField
        FailureTimeoutmsEditFieldLabel  matlab.ui.control.Label
        IntertrialIntervalmsEditField   matlab.ui.control.NumericEditField
        IntertrialIntervalmsEditFieldLabel  matlab.ui.control.Label
        DecisionTargetFixationTimemsEditField  matlab.ui.control.EditField
        DecisionTargetFixationTimemsEditFieldLabel  matlab.ui.control.Label
        FixationPointOnlyTimemsEditField  matlab.ui.control.NumericEditField
        FixationPointOnlyTimemsEditFieldLabel  matlab.ui.control.Label
        RDKStimulusTimemsEditField      matlab.ui.control.NumericEditField
        RDKStimulusTimemsEditFieldLabel  matlab.ui.control.Label
        RDKStimulusPanel                matlab.ui.container.Panel
        DirectionsofMotiontoIncludeonlyLRworkLabel  matlab.ui.control.Label
        DOWNButton                      matlab.ui.control.StateButton
        LEFTButton                      matlab.ui.control.StateButton
        UPButton                        matlab.ui.control.StateButton
        RIGHTButton                     matlab.ui.control.StateButton
        DotVelocitydegsecEditField      matlab.ui.control.NumericEditField
        DotVelocityLabel                matlab.ui.control.Label
        WindowAngledegDropDown          matlab.ui.control.DropDown
        WindowAngledegDropDownLabel     matlab.ui.control.Label
        ApplyParametersButton           matlab.ui.control.Button
    end

    
    properties (Access = private)
        %data_cell = cell(40,1) ; % Cell for all of GUI data input to be written to .txt file
        data_cell(60,1) = {0};  
                
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

%           app.RewardAngledegDropDown.Value = 
%           app.RDKStimulusTimemsEditField.Value = 
%           app.Button_5.Value = 
%           app.IntertrialIntervalmsEditField.Value
%           app.FixationPointOnlyTimemsEditField.Value
%           app.FailureTimeoutmsEditField.Value
%           app.WindowAngledegDropDown.Value
%           app.DecisionTargetFixationTimemsEditField.Value
%           app.DotVelocitydegsecEditField.Value
%           app.LEFTButton.Value
%           app.RIGHTButton.Value
%           app.AUDStartTimemsEditField.Value
%           app.VelocitydegsecEditField.Value
%           app.LtoRCheckBox.Value
%           app.RtoLCheckBox.Value
          
        end

        % Value changed function: TargetAngledegDropDown
        function TargetAngledegDropDownValueChanged(app, event)
            t_angle = app.TargetAngledegDropDown.Value;
            t_angle = str2double(t_angle);
            app.data_cell{1,1} = t_angle; 
        end

        % Value changed function: RewardAngledegDropDown
        function RewardAngledegDropDownValueChanged(app, event)
            w_angle = app.RewardAngledegDropDown.Value;
            w_angle = str2double(w_angle);
            app.data_cell{2,1} = w_angle;
        end

        % Button pushed function: ApplyParametersButton
        function ApplyParametersButtonPushed(app, event)

            writecell(app.data_cell,"data_cell.txt")
            disp("Data Written to File")
        end

        % Value changed function: RDKStimulusTimemsEditField
        function RDKStimulusTimemsEditFieldValueChanged(app, event)
            app.data_cell{4,1} = app.RDKStimulusTimemsEditField.Value;
            
        end

        % Value changed function: Button_1
        function Button_1_1ValueChanged(app, event)
            app.data_cell{7,1} = app.Button_1.Value;
            
        end

        % Value changed function: Button_2
        function Button_2ValueChanged(app, event)
           app.data_cell{8,1} = app.Button_2.Value;
            
        end

        % Value changed function: Button_3
        function Button_3ValueChanged(app, event)
            app.data_cell{9,1} = app.Button_3.Value;
            
        end

        % Value changed function: Button_4
        function Button_4ValueChanged(app, event)
            app.data_cell{10,1} = app.Button_4.Value;
            
        end

        % Value changed function: Button_5
        function Button_5ValueChanged(app, event)
            app.data_cell{11,1} = app.Button_5.Value;
            
        end

        % Value changed function: Button_6
        function Button_6ValueChanged(app, event)
            app.data_cell{12,1} = app.Button_6.Value;
            
        end

        % Value changed function: Button_7
        function Button_7ValueChanged(app, event)
            app.data_cell{13,1} = app.Button_7.Value;
            
        end

        % Value changed function: Button_8
        function Button_8ValueChanged(app, event)
            app.data_cell{14,1} = app.Button_8.Value;
            
        end

        % Value changed function: Button_9
        function Button_9ValueChanged(app, event)
            app.data_cell{15,1} = app.Button_9.Value;
            
        end

        % Value changed function: IntertrialIntervalmsEditField
        function IntertrialIntervalmsEditFieldValueChanged(app, event)
            app.data_cell{5,1} = app.IntertrialIntervalmsEditField.Value;
            
        end

        % Value changed function: FixationPointOnlyTimemsEditField
        function FixationPointOnlyTimemsEditFieldValueChanged(app, event)
            app.data_cell{6,1} = app.FixationPointOnlyTimemsEditField.Value;
            
        end

        % Value changed function: FailureTimeoutmsEditField
        function FailureTimeoutmsEditFieldValueChanged(app, event)
            app.data_cell{16,1} = app.FailureTimeoutmsEditField.Value;
            
        end

        % Value changed function: WindowAngledegDropDown
        function WindowAngledegDropDownValueChanged(app, event)
            rdk_angle = app.WindowAngledegDropDown.Value;
            rdk_angle = str2double(rdk_angle);
            app.data_cell{17,1} = rdk_angle;
        end

        % Value changed function: 
        % DecisionTargetFixationTimemsEditField
        function DecisionTargetFixationTimemsEditFieldValueChanged(app, event)
            app.data_cell{18,1} = app.DecisionTargetFixationTimemsEditField.Value;
        end

        % Value changed function: DotVelocitydegsecEditField
        function DotVelocitydegsecEditFieldValueChanged(app, event)
             app.data_cell{20,1} = app.DotVelocitydegsecEditField.Value;
            
        end

        % Value changed function: DOWNButton
        function DOWNButtonValueChanged(app, event)
            app.data_cell{21,1} = app.DOWNButton.Value;
            
        end

        % Value changed function: LEFTButton
        function LEFTButtonValueChanged(app, event)
            app.data_cell{22,1} = app.LEFTButton.Value;
            
        end

        % Value changed function: UPButton
        function UPButtonValueChanged(app, event)
            app.data_cell{23,1} = app.UPButton.Value;
            
        end

        % Value changed function: RIGHTButton
        function RIGHTButtonValueChanged(app, event)
            app.data_cell{24,1} = app.RIGHTButton.Value;
            
        end

        % Value changed function: AUDStartTimemsEditField
        function AUDStartTimemsEditFieldValueChanged(app, event)
            app.data_cell{27,1} = app.AUDStartTimemsEditField.Value;
            
        end

        % Value changed function: VelocitydegsecEditField
        function VelocitydegsecEditFieldValueChanged(app, event)
            app.data_cell{29,1} = app.VelocitydegsecEditField.Value;
            
        end

        % Value changed function: LtoRCheckBox
        function LtoRCheckBoxValueChanged(app, event)
            app.data_cell{30,1} = app.LtoRCheckBox.Value;
            
        end

        % Value changed function: RtoLCheckBox
        function RtoLCheckBoxValueChanged(app, event)
            app.data_cell{33,1} = app.RtoLCheckBox.Value;
            
        end

        % Value changed function: UtoDCheckBox
        function UtoDCheckBoxValueChanged(app, event)
            app.data_cell{32,1} = app.UtoDCheckBox.Value;
            
        end

        % Value changed function: DtoUCheckBox
        function DtoUCheckBoxValueChanged(app, event)
            app.data_cell{31,1} = app.DtoUCheckBox.Value;
            
        end

        % Button pushed function: CloseFigureButton
        function CloseFigureButtonPushed(app, event)
            close(app.UIFigure);
        end

        % Callback function
        function StartExperimentButtonPushed(app, event)
            app.StartExperimentButton.UserData = 1; 
        end

        % Value changed function: AUDEndTimemsEditField
        function AUDEndTimemsEditFieldValueChanged(app, event)
            app.data_cell{28,1} = app.AUDEndTimemsEditField.Value;
            
        end

        % Button pushed function: CalcTotalAUDStimTImeButton
        function CalcTotalAUDStimTImeButtonPushed(app, event)
           total_time = (app.data_cell{28,1} - app.data_cell{27,1}); %End Time - Start Time        
           app.TotalAUDStimTimemsTextArea.Value = num2str(total_time);
        end

        % Button pushed function: CalcTotalset_durTimeButton
        function CalcTotalset_durTimeButtonPushed(app, event)
            app.set_durtimetotalavailmsTextArea.Value = num2str(78/app.data_cell{29,1}*1000);
        end

        % Value changed function: EditField_1
        function EditField_1ValueChanged(app, event)
            app.data_cell{50,1} = app.EditField_1.Value;
            
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            app.data_cell{51,1}= app.EditField_2.Value;
            
        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)
            app.data_cell{52,1}= app.EditField_3.Value;
            
        end

        % Value changed function: EditField_4
        function EditField_4ValueChanged(app, event)
            app.data_cell{53,1} = app.EditField_4.Value;
            
        end

        % Value changed function: EditField_5
        function EditField_5ValueChanged(app, event)
            app.data_cell{54,1} = app.EditField_5.Value;
            
        end

        % Value changed function: EditField_6
        function EditField_6ValueChanged(app, event)
            app.data_cell{55,1}= app.EditField_6.Value;
            
        end

        % Value changed function: EditField_7
        function EditField_7ValueChanged(app, event)
            app.data_cell{56,1} = app.EditField_7.Value;
            
        end

        % Value changed function: EditField_8
        function EditField_8ValueChanged(app, event)
            app.data_cell{57,1} = app.EditField_8.Value;
            
        end

        % Value changed function: EditField_9
        function EditField_9ValueChanged(app, event)
            app.data_cell{58,1} = app.EditField_9.Value;
            
        end

        % Value changed function: EditField_10
        function EditField_10ValueChanged(app, event)
            app.data_cell{59,1} = app.EditField_10.Value;
            
        end

        % Value changed function: EditField_11
        function EditField_11ValueChanged(app, event)
            app.data_cell{60,1} = app.EditField_11.Value;
            
        end

        % Button pushed function: CalcTotalTrialsButton
        function CalcTotalTrialsButtonPushed(app, event)
            total = sum(cell2mat(app.data_cell(50:60,1)));
            app.data_cell{3,1} = total; 
            app.TotalTrialsTextArea.Value = num2str(total);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ApplyParametersButton = uibutton(app.UIFigure, 'push');
            app.ApplyParametersButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyParametersButtonPushed, true);
            app.ApplyParametersButton.Text = 'Apply Parameters';
            app.RDKStimulusPanel = uipanel(app.UIFigure);
            app.RDKStimulusPanel.Title = 'RDK Stimulus';
            app.WindowAngledegDropDownLabel = uilabel(app.RDKStimulusPanel);
            app.WindowAngledegDropDownLabel.Text = 'Window Angle (deg)';
            app.WindowAngledegDropDown = uidropdown(app.RDKStimulusPanel);
            app.WindowAngledegDropDown.Items = {'0', '25', '22', '20', '17', '15', '12.5', '10', '7.5', '5', '2.5'};
            app.WindowAngledegDropDown.ItemsData = {'0', '25', '22', '20', '17', '15', '12.5', '10', '7.5', '5', '2.5'};
            app.WindowAngledegDropDown.ValueChangedFcn = createCallbackFcn(app, @WindowAngledegDropDownValueChanged, true);
            app.WindowAngledegDropDown.Value = '0';
            app.DotVelocityLabel = uilabel(app.RDKStimulusPanel);
            app.DotVelocityLabel.Text = 'Dot Velocity (deg/sec)';
            app.DotVelocitydegsecEditField = uieditfield(app.RDKStimulusPanel, 'numeric');
            app.DotVelocitydegsecEditField.ValueChangedFcn = createCallbackFcn(app, @DotVelocitydegsecEditFieldValueChanged, true);
            app.RIGHTButton = uibutton(app.RDKStimulusPanel, 'state');
            app.RIGHTButton.ValueChangedFcn = createCallbackFcn(app, @RIGHTButtonValueChanged, true);
            app.RIGHTButton.Text = 'RIGHT';
            app.UPButton = uibutton(app.RDKStimulusPanel, 'state');
            app.UPButton.ValueChangedFcn = createCallbackFcn(app, @UPButtonValueChanged, true);
            app.UPButton.Text = 'UP';
            app.LEFTButton = uibutton(app.RDKStimulusPanel, 'state');
            app.LEFTButton.ValueChangedFcn = createCallbackFcn(app, @LEFTButtonValueChanged, true);
            app.LEFTButton.Text = 'LEFT';
            app.DOWNButton = uibutton(app.RDKStimulusPanel, 'state');
            app.DOWNButton.ValueChangedFcn = createCallbackFcn(app, @DOWNButtonValueChanged, true);
            app.DOWNButton.Text = 'DOWN';
            app.DirectionsofMotiontoIncludeonlyLRworkLabel = uilabel(app.RDKStimulusPanel);
            app.DirectionsofMotiontoIncludeonlyLRworkLabel.Text = {'Directions of Motion to '; 'Include (only L & R work)'};
            app.ExperimentParametersPanel = uipanel(app.UIFigure);
            app.ExperimentParametersPanel.Title = 'Experiment Parameters';
            app.RDKStimulusTimemsEditFieldLabel = uilabel(app.ExperimentParametersPanel);
            app.RDKStimulusTimemsEditFieldLabel.Text = 'RDK  Stimulus Time (ms)';
            app.RDKStimulusTimemsEditField = uieditfield(app.ExperimentParametersPanel, 'numeric');
            app.RDKStimulusTimemsEditField.ValueChangedFcn = createCallbackFcn(app, @RDKStimulusTimemsEditFieldValueChanged, true);
            app.FixationPointOnlyTimemsEditFieldLabel = uilabel(app.ExperimentParametersPanel);
            app.FixationPointOnlyTimemsEditFieldLabel.Text = 'Fixation Point Only Time (ms)';
            app.FixationPointOnlyTimemsEditField = uieditfield(app.ExperimentParametersPanel, 'numeric');
            app.FixationPointOnlyTimemsEditField.ValueChangedFcn = createCallbackFcn(app, @FixationPointOnlyTimemsEditFieldValueChanged, true);
            app.DecisionTargetFixationTimemsEditFieldLabel = uilabel(app.ExperimentParametersPanel);
            app.DecisionTargetFixationTimemsEditFieldLabel.Text = 'Decision Target FixationTime (ms)';
            app.DecisionTargetFixationTimemsEditField = uieditfield(app.ExperimentParametersPanel, 'text');
            app.DecisionTargetFixationTimemsEditField.ValueChangedFcn = createCallbackFcn(app, @DecisionTargetFixationTimemsEditFieldValueChanged, true);
            app.DecisionTargetFixationTimemsEditField.Value = '0';
            app.IntertrialIntervalmsEditFieldLabel = uilabel(app.ExperimentParametersPanel);
            app.IntertrialIntervalmsEditFieldLabel.Text = 'Intertrial Interval (ms)';
            app.IntertrialIntervalmsEditField = uieditfield(app.ExperimentParametersPanel, 'numeric');
            app.IntertrialIntervalmsEditField.ValueChangedFcn = createCallbackFcn(app, @IntertrialIntervalmsEditFieldValueChanged, true);
            app.FailureTimeoutmsEditFieldLabel = uilabel(app.ExperimentParametersPanel);
            app.FailureTimeoutmsEditFieldLabel.Text = 'Failure Timeout (ms)';
            app.FailureTimeoutmsEditField = uieditfield(app.ExperimentParametersPanel, 'text');
            app.FailureTimeoutmsEditField.ValueChangedFcn = createCallbackFcn(app, @FailureTimeoutmsEditFieldValueChanged, true);
            app.FailureTimeoutmsEditField.Value = '0';
            app.CentralPositionsforFixationPanel = uipanel(app.UIFigure);
            app.CentralPositionsforFixationPanel.Title = 'Central Positions for Fixation ';
            app.Button_1 = uibutton(app.CentralPositionsforFixationPanel, 'state');
            app.Button_1.ValueChangedFcn = createCallbackFcn(app, @Button_1_1ValueChanged, true);
            app.Button_1.Text = '1';
            app.Button_2 = uibutton(app.CentralPositionsforFixationPanel, 'state');
            app.Button_2.ValueChangedFcn = createCallbackFcn(app, @Button_2ValueChanged, true);
            app.Button_2.Text = '2';
            app.Button_3 = uibutton(app.CentralPositionsforFixationPanel, 'state');
            app.Button_3.ValueChangedFcn = createCallbackFcn(app, @Button_3ValueChanged, true);
            app.Button_3.Text = '3';
            app.Button_4 = uibutton(app.CentralPositionsforFixationPanel, 'state');
            app.Button_4.ValueChangedFcn = createCallbackFcn(app, @Button_4ValueChanged, true);
            app.Button_4.Text = '4';
            app.Button_5 = uibutton(app.CentralPositionsforFixationPanel, 'state');
            app.Button_5.ValueChangedFcn = createCallbackFcn(app, @Button_5ValueChanged, true);
            app.Button_5.Text = '5';
            app.Button_6 = uibutton(app.CentralPositionsforFixationPanel, 'state');
            app.Button_6.ValueChangedFcn = createCallbackFcn(app, @Button_6ValueChanged, true);
            app.Button_6.Text = '6';
            app.Button_7 = uibutton(app.CentralPositionsforFixationPanel, 'state');
            app.Button_7.ValueChangedFcn = createCallbackFcn(app, @Button_7ValueChanged, true);
            app.Button_7.Text = '7';
            app.Button_8 = uibutton(app.CentralPositionsforFixationPanel, 'state');
            app.Button_8.ValueChangedFcn = createCallbackFcn(app, @Button_8ValueChanged, true);
            app.Button_8.Text = '8';
            app.Button_9 = uibutton(app.CentralPositionsforFixationPanel, 'state');
            app.Button_9.ValueChangedFcn = createCallbackFcn(app, @Button_9ValueChanged, true);
            app.Button_9.Text = '9';
            app.FixationStimulusPanel = uipanel(app.UIFigure);
            app.FixationStimulusPanel.Title = 'Fixation Stimulus';
            app.TargetAngledegDropDownLabel = uilabel(app.FixationStimulusPanel);
            app.TargetAngledegDropDownLabel.Text = 'Target Angle (deg)';
            app.TargetAngledegDropDown = uidropdown(app.FixationStimulusPanel);
            app.TargetAngledegDropDown.Items = {'0', '.1', '.2', '.3', '.4', '.5', '.6', '.7', '.8', '.9', '1', '1.1', '1.2', '1.3', '1.4', '1.5', '1.6', '1.7', ''};
            app.TargetAngledegDropDown.ItemsData = {'0', '.1', '.2', '.3', '.4', '.5', '.6', '.7', '.8', '.9', '1', '1.1', '1.2', '1.3', '1.4', '1.5', '1.6', '1.7', ''};
            app.TargetAngledegDropDown.ValueChangedFcn = createCallbackFcn(app, @TargetAngledegDropDownValueChanged, true);
            app.TargetAngledegDropDown.Value = '0';
            app.RewardAngledegDropDownLabel = uilabel(app.FixationStimulusPanel);
            app.RewardAngledegDropDownLabel.Text = 'Reward Angle (deg)';
            app.RewardAngledegDropDown = uidropdown(app.FixationStimulusPanel);
            app.RewardAngledegDropDown.Items = {'0', '30', '27', '26', '25', '23', '22', '20', '17', '15', '14', '13', '12.5', '11', '10', '9', '8', '7.5', '7', '5', '2.5'};
            app.RewardAngledegDropDown.ItemsData = {'0', '30', '27', '26', '25', '23', '22', '20', '17', '15', '14', '13', '12.5', '11', '10', '9', '8', '7.5', '7', '5', '2.5'};
            app.RewardAngledegDropDown.ValueChangedFcn = createCallbackFcn(app, @RewardAngledegDropDownValueChanged, true);
            app.RewardAngledegDropDown.Value = '0';
            app.AuditoryStimulusPanel = uipanel(app.UIFigure);
            app.AuditoryStimulusPanel.Title = 'Auditory Stimulus ';
            app.VelocitydegsecEditFieldLabel = uilabel(app.AuditoryStimulusPanel);
            app.VelocitydegsecEditFieldLabel.Text = 'Velocity (deg/sec)';
            app.VelocitydegsecEditField = uieditfield(app.AuditoryStimulusPanel, 'numeric');
            app.VelocitydegsecEditField.ValueChangedFcn = createCallbackFcn(app, @VelocitydegsecEditFieldValueChanged, true);
            app.LtoRCheckBox = uicheckbox(app.AuditoryStimulusPanel);
            app.LtoRCheckBox.ValueChangedFcn = createCallbackFcn(app, @LtoRCheckBoxValueChanged, true);
            app.LtoRCheckBox.Text = 'L to R';
            app.RtoLCheckBox = uicheckbox(app.AuditoryStimulusPanel);
            app.RtoLCheckBox.ValueChangedFcn = createCallbackFcn(app, @RtoLCheckBoxValueChanged, true);
            app.RtoLCheckBox.Text = 'R to L';
            app.UtoDCheckBox = uicheckbox(app.AuditoryStimulusPanel);
            app.UtoDCheckBox.ValueChangedFcn = createCallbackFcn(app, @UtoDCheckBoxValueChanged, true);
            app.UtoDCheckBox.Text = 'U to D';
            app.DtoUCheckBox = uicheckbox(app.AuditoryStimulusPanel);
            app.DtoUCheckBox.ValueChangedFcn = createCallbackFcn(app, @DtoUCheckBoxValueChanged, true);
            app.DtoUCheckBox.Text = 'D to U';
            app.AUDStartTimemsEditFieldLabel = uilabel(app.AuditoryStimulusPanel);
            app.AUDStartTimemsEditFieldLabel.Text = 'AUD Start Time (ms)';
            app.AUDStartTimemsEditField = uieditfield(app.AuditoryStimulusPanel, 'numeric');
            app.AUDStartTimemsEditField.ValueChangedFcn = createCallbackFcn(app, @AUDStartTimemsEditFieldValueChanged, true);
            app.AUDEndTimemsEditFieldLabel = uilabel(app.AuditoryStimulusPanel);
            app.AUDEndTimemsEditFieldLabel.Text = 'AUD End Time (ms)';
            app.AUDEndTimemsEditField = uieditfield(app.AuditoryStimulusPanel, 'numeric');
            app.AUDEndTimemsEditField.ValueChangedFcn = createCallbackFcn(app, @AUDEndTimemsEditFieldValueChanged, true);
            app.CalcTotalAUDStimTImeButton = uibutton(app.AuditoryStimulusPanel, 'push');
            app.CalcTotalAUDStimTImeButton.ButtonPushedFcn = createCallbackFcn(app, @CalcTotalAUDStimTImeButtonPushed, true);
            app.CalcTotalAUDStimTImeButton.Text = 'Calc. Total AUD Stim TIme';
            app.CalcTotalset_durTimeButton = uibutton(app.AuditoryStimulusPanel, 'push');
            app.CalcTotalset_durTimeButton.ButtonPushedFcn = createCallbackFcn(app, @CalcTotalset_durTimeButtonPushed, true);
            app.CalcTotalset_durTimeButton.Text = 'Calc. Total set_dur Time';
            app.StimulusCoherenceRegularTrialsPanel = uipanel(app.UIFigure);
            app.StimulusCoherenceRegularTrialsPanel.Title = 'Stimulus Coherence (RegularTrials)';
            app.Label = uilabel(app.StimulusCoherenceRegularTrialsPanel);
            app.Label.Text = '100%';
            app.EditField_1 = uieditfield(app.StimulusCoherenceRegularTrialsPanel, 'numeric');
            app.EditField_1.ValueChangedFcn = createCallbackFcn(app, @EditField_1ValueChanged, true);
            app.Label_2 = uilabel(app.StimulusCoherenceRegularTrialsPanel);
            app.Label_2.Text = '70.7%';
            app.EditField_2 = uieditfield(app.StimulusCoherenceRegularTrialsPanel, 'numeric');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.Label_3 = uilabel(app.StimulusCoherenceRegularTrialsPanel);
            app.Label_3.Text = '50%';
            app.EditField_3 = uieditfield(app.StimulusCoherenceRegularTrialsPanel, 'numeric');
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            app.Label_4 = uilabel(app.StimulusCoherenceRegularTrialsPanel);
            app.Label_4.Text = '35.4%';
            app.EditField_4 = uieditfield(app.StimulusCoherenceRegularTrialsPanel, 'numeric');
            app.EditField_4.ValueChangedFcn = createCallbackFcn(app, @EditField_4ValueChanged, true);
            app.Label_5 = uilabel(app.StimulusCoherenceRegularTrialsPanel);
            app.Label_5.Text = '25%';
            app.EditField_5 = uieditfield(app.StimulusCoherenceRegularTrialsPanel, 'numeric');
            app.EditField_5.ValueChangedFcn = createCallbackFcn(app, @EditField_5ValueChanged, true);
            app.Label_6 = uilabel(app.StimulusCoherenceRegularTrialsPanel);
            app.Label_6.Text = '17.7%';
            app.EditField_6 = uieditfield(app.StimulusCoherenceRegularTrialsPanel, 'numeric');
            app.EditField_6.ValueChangedFcn = createCallbackFcn(app, @EditField_6ValueChanged, true);
            app.Label_7 = uilabel(app.StimulusCoherenceRegularTrialsPanel);
            app.Label_7.Text = '12.5%';
            app.EditField_7 = uieditfield(app.StimulusCoherenceRegularTrialsPanel, 'numeric');
            app.EditField_7.ValueChangedFcn = createCallbackFcn(app, @EditField_7ValueChanged, true);
            app.Label_8 = uilabel(app.StimulusCoherenceRegularTrialsPanel);
            app.Label_8.Text = '8.9%';
            app.EditField_8 = uieditfield(app.StimulusCoherenceRegularTrialsPanel, 'numeric');
            app.EditField_8.ValueChangedFcn = createCallbackFcn(app, @EditField_8ValueChanged, true);
            app.Label_9 = uilabel(app.StimulusCoherenceRegularTrialsPanel);
            app.Label_9.Text = '6.3%';
            app.EditField_9 = uieditfield(app.StimulusCoherenceRegularTrialsPanel, 'numeric');
            app.EditField_9.ValueChangedFcn = createCallbackFcn(app, @EditField_9ValueChanged, true);
            app.Label_10 = uilabel(app.StimulusCoherenceRegularTrialsPanel);
            app.Label_10.Text = '4.5%';
            app.EditField_10 = uieditfield(app.StimulusCoherenceRegularTrialsPanel, 'numeric');
            app.EditField_10.ValueChangedFcn = createCallbackFcn(app, @EditField_10ValueChanged, true);
            app.Label_11 = uilabel(app.StimulusCoherenceRegularTrialsPanel);
            app.Label_11.Text = '3.2%';
            app.EditField_11 = uieditfield(app.StimulusCoherenceRegularTrialsPanel, 'numeric');
            app.EditField_11.ValueChangedFcn = createCallbackFcn(app, @EditField_11ValueChanged, true);
            app.NumberofTrialsforEachcoherenceLevelLabel = uilabel(app.StimulusCoherenceRegularTrialsPanel);
            app.NumberofTrialsforEachcoherenceLevelLabel.Text = 'Number of Trials for Each coherence Level:';
            app.CalcTotalTrialsButton = uibutton(app.StimulusCoherenceRegularTrialsPanel, 'push');
            app.CalcTotalTrialsButton.ButtonPushedFcn = createCallbackFcn(app, @CalcTotalTrialsButtonPushed, true);
            app.CalcTotalTrialsButton.Text = 'Calc. Total Trials';
            app.TotalTrialsTextAreaLabel = uilabel(app.StimulusCoherenceRegularTrialsPanel);
            app.TotalTrialsTextAreaLabel.Text = 'Total # Trials';
            app.TotalTrialsTextArea = uitextarea(app.StimulusCoherenceRegularTrialsPanel);
            app.CloseFigureButton = uibutton(app.UIFigure, 'push');
            app.CloseFigureButton.ButtonPushedFcn = createCallbackFcn(app, @CloseFigureButtonPushed, true);
            app.CloseFigureButton.Text = 'Close Figure';
            app.AuditoryStimulusContPanel = uipanel(app.UIFigure);
            app.AuditoryStimulusContPanel.Title = 'Auditory Stimulus Cont. ';
            app.TotalAUDStimTimemsLabel = uilabel(app.AuditoryStimulusContPanel);
            app.TotalAUDStimTimemsLabel.Text = {'Total AUD Stim'; 'Time (ms)'};
            app.TotalAUDStimTimemsTextArea = uitextarea(app.AuditoryStimulusContPanel);
            app.AUDStarttoEndTImesmustnotbelongerthan2500msLabel = uilabel(app.AuditoryStimulusContPanel);
            app.AUDStarttoEndTImesmustnotbelongerthan2500msLabel.Text = {'AUD Start to End Times must '; 'be within values of 0 to set_dur, and total time must not '; 'exceed set_dur ms'};
            app.set_durtimetotalavailmsTextAreaLabel = uilabel(app.AuditoryStimulusContPanel);
            app.set_durtimetotalavailmsTextAreaLabel.Text = {'set_dur time  '; '(total avail. ms)'};
            app.set_durtimetotalavailmsTextArea = uitextarea(app.AuditoryStimulusContPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MCS_Experiment_Parameters

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end