classdef Experiment_Parameters < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        AuditoryStimulusPanel           matlab.ui.container.Panel
        CalcTotalset_durTimeButton      matlab.ui.control.Button
        CalcTotalAUDStimTImeButton      matlab.ui.control.Button
        AUDEndTimemsEditField           matlab.ui.control.NumericEditField
        AUDEndTimemsEditFieldLabel      matlab.ui.control.Label
        AUDStartTimemsEditField         matlab.ui.control.NumericEditField
        AUDStartTimemsEditFieldLabel    matlab.ui.control.Label
        DtoUCheckBox                    matlab.ui.control.CheckBox
        UtoDCheckBox                    matlab.ui.control.CheckBox
        RtoLCheckBox                    matlab.ui.control.CheckBox
        LtoRCheckBox                    matlab.ui.control.CheckBox
        VelocitydegsecEditField         matlab.ui.control.NumericEditField
        VelocitydegsecEditFieldLabel    matlab.ui.control.Label
        AuditoryStimulusContPanel       matlab.ui.container.Panel
        set_durtimetotalavailmsTextArea  matlab.ui.control.TextArea
        set_durtimetotalavailmsTextAreaLabel  matlab.ui.control.Label
        AUDStarttoEndTImesmustnotbelongerthan2500msLabel  matlab.ui.control.Label
        TotalAUDStimTimemsTextArea      matlab.ui.control.TextArea
        TotalAUDStimTimemsTextAreaLabel  matlab.ui.control.Label
        CloseFigureButton               matlab.ui.control.Button
        StimulusCoherenceRegularTrialsPanel  matlab.ui.container.Panel
        CoherencesAVconditionusestheseListsPanel  matlab.ui.container.Panel
        VISCoherencesEditField          matlab.ui.control.EditField
        VISCoherencesEditFieldLabel     matlab.ui.control.Label
        EnterCohswithspaceinbetweeneachex100908050MaxofCohs13Label  matlab.ui.control.Label
        AUDCoherencesEditField          matlab.ui.control.EditField
        AUDCoherencesEditFieldLabel     matlab.ui.control.Label
        ProbabilitiesPanel              matlab.ui.container.Panel
        fEditField                      matlab.ui.control.NumericEditField
        fEditFieldLabel                 matlab.ui.control.Label
        eEditField                      matlab.ui.control.NumericEditField
        eEditFieldLabel                 matlab.ui.control.Label
        dEditField                      matlab.ui.control.NumericEditField
        dEditFieldLabel                 matlab.ui.control.Label
        cEditField                      matlab.ui.control.NumericEditField
        cEditFieldLabel                 matlab.ui.control.Label
        bEditField                      matlab.ui.control.NumericEditField
        bEditFieldLabel                 matlab.ui.control.Label
        aEditField                      matlab.ui.control.NumericEditField
        aEditFieldLabel                 matlab.ui.control.Label
        FixationStimulusPanel           matlab.ui.container.Panel
        RewardAngledegDropDown          matlab.ui.control.DropDown
        RewardAngledegDropDownLabel     matlab.ui.control.Label
        TargetAngledegDropDown          matlab.ui.control.DropDown
        TargetAngledegDropDownLabel     matlab.ui.control.Label
        CentralPositionsforFixationPanel  matlab.ui.container.Panel
        Button_9                        matlab.ui.control.StateButton
        Button_8                        matlab.ui.control.StateButton
        Button_7                        matlab.ui.control.StateButton
        Button_6                        matlab.ui.control.StateButton
        Button_5                        matlab.ui.control.StateButton
        Button_4                        matlab.ui.control.StateButton
        Button_3                        matlab.ui.control.StateButton
        Button_2                        matlab.ui.control.StateButton
        Button_1                        matlab.ui.control.StateButton
        ExperimentParametersPanel       matlab.ui.container.Panel
        CatchTrialsEditField            matlab.ui.control.NumericEditField
        CatchTrialsEditFieldLabel       matlab.ui.control.Label
        TotalTrialsEditField            matlab.ui.control.NumericEditField
        TotalTrialsEditFieldLabel       matlab.ui.control.Label
        FailureTimeoutmsEditField       matlab.ui.control.EditField
        FailureTimeoutmsEditFieldLabel  matlab.ui.control.Label
        IntertrialIntervalmsEditField   matlab.ui.control.NumericEditField
        IntertrialIntervalmsEditFieldLabel  matlab.ui.control.Label
        DecisionTargetFixationTimemsEditField  matlab.ui.control.EditField
        DecisionTargetFixationTimemsEditFieldLabel  matlab.ui.control.Label
        FixationPointOnlyTimemsEditField  matlab.ui.control.NumericEditField
        FixationPointOnlyTimemsEditFieldLabel  matlab.ui.control.Label
        RDKStimulusTimemsEditField      matlab.ui.control.NumericEditField
        RDKStimulusTimemsEditFieldLabel  matlab.ui.control.Label
        RDKStimulusPanel                matlab.ui.container.Panel
        DirectionsofMotiontoIncludeonlyLRworkLabel  matlab.ui.control.Label
        DOWNButton                      matlab.ui.control.StateButton
        LEFTButton                      matlab.ui.control.StateButton
        UPButton                        matlab.ui.control.StateButton
        RIGHTButton                     matlab.ui.control.StateButton
        DotVelocitydegsecEditField      matlab.ui.control.NumericEditField
        DotVelocityLabel                matlab.ui.control.Label
        WindowAngledegDropDown          matlab.ui.control.DropDown
        WindowAngledegDropDownLabel     matlab.ui.control.Label
        ApplyParametersButton           matlab.ui.control.Button
    end

    
    properties (Access = private)
        %data_cell = cell(40,1) ; % Cell for all of GUI data input to be written to .txt file
        data_cell(72,1) = {0};  
                
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

%           app.RewardAngledegDropDown.Value = 
%           app.RDKStimulusTimemsEditField.Value = 
%           app.Button_5.Value = 
%           app.IntertrialIntervalmsEditField.Value
%           app.FixationPointOnlyTimemsEditField.Value
%           app.FailureTimeoutmsEditField.Value
%           app.WindowAngledegDropDown.Value
%           app.DecisionTargetFixationTimemsEditField.Value
%           app.DotVelocitydegsecEditField.Value
%           app.LEFTButton.Value
%           app.RIGHTButton.Value
%           app.AUDStartTimemsEditField.Value
%           app.VelocitydegsecEditField.Value
%           app.LtoRCheckBox.Value
%           app.RtoLCheckBox.Value
          
        end

        % Value changed function: TargetAngledegDropDown
        function TargetAngledegDropDownValueChanged(app, event)
            t_angle = app.TargetAngledegDropDown.Value;
            t_angle = str2double(t_angle);
            app.data_cell{1,1} = t_angle; 
        end

        % Value changed function: RewardAngledegDropDown
        function RewardAngledegDropDownValueChanged(app, event)
            w_angle = app.RewardAngledegDropDown.Value;
            w_angle = str2double(w_angle);
            app.data_cell{2,1} = w_angle;
        end

        % Button pushed function: ApplyParametersButton
        function ApplyParametersButtonPushed(app, event)

            writecell(app.data_cell,"data_cell.txt")
            disp("Data Written to File")
        end

        % Value changed function: RDKStimulusTimemsEditField
        function RDKStimulusTimemsEditFieldValueChanged(app, event)
            app.data_cell{4,1} = app.RDKStimulusTimemsEditField.Value;
            
        end

        % Value changed function: Button_1
        function Button_1_1ValueChanged(app, event)
            app.data_cell{7,1} = app.Button_1.Value;
            
        end

        % Value changed function: Button_2
        function Button_2ValueChanged(app, event)
           app.data_cell{8,1} = app.Button_2.Value;
            
        end

        % Value changed function: Button_3
        function Button_3ValueChanged(app, event)
            app.data_cell{9,1} = app.Button_3.Value;
            
        end

        % Value changed function: Button_4
        function Button_4ValueChanged(app, event)
            app.data_cell{10,1} = app.Button_4.Value;
            
        end

        % Value changed function: Button_5
        function Button_5ValueChanged(app, event)
            app.data_cell{11,1} = app.Button_5.Value;
            
        end

        % Value changed function: Button_6
        function Button_6ValueChanged(app, event)
            app.data_cell{12,1} = app.Button_6.Value;
            
        end

        % Value changed function: Button_7
        function Button_7ValueChanged(app, event)
            app.data_cell{13,1} = app.Button_7.Value;
            
        end

        % Value changed function: Button_8
        function Button_8ValueChanged(app, event)
            app.data_cell{14,1} = app.Button_8.Value;
            
        end

        % Value changed function: Button_9
        function Button_9ValueChanged(app, event)
            app.data_cell{15,1} = app.Button_9.Value;
            
        end

        % Value changed function: IntertrialIntervalmsEditField
        function IntertrialIntervalmsEditFieldValueChanged(app, event)
            app.data_cell{5,1} = app.IntertrialIntervalmsEditField.Value;
            
        end

        % Value changed function: FixationPointOnlyTimemsEditField
        function FixationPointOnlyTimemsEditFieldValueChanged(app, event)
            app.data_cell{6,1} = app.FixationPointOnlyTimemsEditField.Value;
            
        end

        % Value changed function: FailureTimeoutmsEditField
        function FailureTimeoutmsEditFieldValueChanged(app, event)
            app.data_cell{16,1} = app.FailureTimeoutmsEditField.Value;
            
        end

        % Value changed function: WindowAngledegDropDown
        function WindowAngledegDropDownValueChanged(app, event)
            rdk_angle = app.WindowAngledegDropDown.Value;
            rdk_angle = str2double(rdk_angle);
            app.data_cell{17,1} = rdk_angle;
        end

        % Value changed function: DecisionTargetFixationTimemsEditField
        function DecisionTargetFixationTimemsEditFieldValueChanged(app, event)
            app.data_cell{18,1} = app.DecisionTargetFixationTimemsEditField.Value;
        end

        % Value changed function: DotVelocitydegsecEditField
        function DotVelocitydegsecEditFieldValueChanged(app, event)
             app.data_cell{20,1} = app.DotVelocitydegsecEditField.Value;
            
        end

        % Value changed function: DOWNButton
        function DOWNButtonValueChanged(app, event)
            app.data_cell{21,1} = app.DOWNButton.Value;
            
        end

        % Value changed function: LEFTButton
        function LEFTButtonValueChanged(app, event)
            app.data_cell{22,1} = app.LEFTButton.Value;
            
        end

        % Value changed function: UPButton
        function UPButtonValueChanged(app, event)
            app.data_cell{23,1} = app.UPButton.Value;
            
        end

        % Value changed function: RIGHTButton
        function RIGHTButtonValueChanged(app, event)
            app.data_cell{24,1} = app.RIGHTButton.Value;
            
        end

        % Value changed function: AUDStartTimemsEditField
        function AUDStartTimemsEditFieldValueChanged(app, event)
            app.data_cell{27,1} = app.AUDStartTimemsEditField.Value;
            
        end

        % Value changed function: VelocitydegsecEditField
        function VelocitydegsecEditFieldValueChanged(app, event)
            app.data_cell{29,1} = app.VelocitydegsecEditField.Value;
            
        end

        % Callback function
        function LtoRCheckBoxValueChanged(app, event)
            app.data_cell{30,1} = app.LtoRCheckBox.Value;
            
        end

        % Callback function
        function RtoLCheckBoxValueChanged(app, event)
            app.data_cell{33,1} = app.RtoLCheckBox.Value;
            
        end

        % Callback function
        function UtoDCheckBoxValueChanged(app, event)
            app.data_cell{32,1} = app.UtoDCheckBox.Value;
            
        end

        % Callback function
        function DtoUCheckBoxValueChanged(app, event)
            app.data_cell{31,1} = app.DtoUCheckBox.Value;
            
        end

        % Button pushed function: CloseFigureButton
        function CloseFigureButtonPushed(app, event)
            close(app.UIFigure);
        end

        % Callback function
        function StartExperimentButtonPushed(app, event)
            app.StartExperimentButton.UserData = 1; 
        end

        % Value changed function: AUDEndTimemsEditField
        function AUDEndTimemsEditFieldValueChanged(app, event)
            app.data_cell{28,1} = app.AUDEndTimemsEditField.Value;
            
        end

        % Callback function
        function CalcTotalAUDStimTImeButtonPushed(app, event)
           total_time = (app.data_cell{28,1} - app.data_cell{27,1}); %End Time - Start Time        
           app.TotalAUDStimTimemsTextArea.Value = num2str(total_time);
        end

        % Value changed function: aEditField
        function aEditFieldValueChanged(app, event)
            app.data_cell{43,1} = app.aEditField.Value;
            
        end

        % Value changed function: bEditField
        function bEditFieldValueChanged(app, event)
            app.data_cell{44,1} = app.bEditField.Value;
            
        end

        % Value changed function: cEditField
        function cEditFieldValueChanged(app, event)
            app.data_cell{45,1} = app.cEditField.Value;
            
        end

        % Value changed function: dEditField
        function dEditFieldValueChanged(app, event)
            app.data_cell{46,1} = app.dEditField.Value;
            
        end

        % Value changed function: AUDCoherencesEditField
        function AUDCoherencesEditFieldValueChanged(app, event)
            value = app.AUDCoherencesEditField.Value;
            number_array = str2num(value);
            for i = 1:length(number_array)
                app.data_cell{49+i, 1} = number_array(i); 
            end
            
        end

        % Value changed function: TotalTrialsEditField
        function TotalTrialsEditFieldValueChanged(app, event)
            app.data_cell{3,1} = app.TotalTrialsEditField.Value;
            
        end

        % Value changed function: eEditField
        function eEditFieldValueChanged(app, event)
            app.data_cell{47,1} = app.eEditField.Value;
            
        end

        % Value changed function: fEditField
        function fEditFieldValueChanged(app, event)
            app.data_cell{48,1}= app.fEditField.Value;
            
        end

        % Value changed function: VISCoherencesEditField
        function VISCoherencesEditFieldValueChanged(app, event)
            value = app.VISCoherencesEditField.Value;
            number_array = str2num(value);
            for i = 1:length(number_array)
                app.data_cell{60+i, 1} = number_array(i); 
            end
        end

        % Button pushed function: CalcTotalAUDStimTImeButton
        function CalcTotalAUDStimTImeButtonPushed2(app, event)
            total_time = (app.data_cell{28,1} - app.data_cell{27,1}); %End Time - Start Time        
           app.TotalAUDStimTimemsTextArea.Value = num2str(total_time);
        end

        % Button pushed function: CalcTotalset_durTimeButton
        function CalcTotalset_durTimeButtonPushed(app, event)
            app.set_durtimetotalavailmsTextArea.Value = num2str(78/app.data_cell{29,1}*1000);
        end

        % Value changed function: CatchTrialsEditField
        function CatchTrialsEditFieldValueChanged(app, event)
            app.data_cell{72,1} = app.CatchTrialsEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ApplyParametersButton = uibutton(app.UIFigure, 'push');
            app.ApplyParametersButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyParametersButtonPushed, true);
            app.ApplyParametersButton.Text = 'Apply Parameters';
            app.RDKStimulusPanel = uipanel(app.UIFigure);
            app.RDKStimulusPanel.Title = 'RDK Stimulus';
            app.WindowAngledegDropDownLabel = uilabel(app.RDKStimulusPanel);
            app.WindowAngledegDropDownLabel.Text = 'Window Angle (deg)';
            app.WindowAngledegDropDown = uidropdown(app.RDKStimulusPanel);
            app.WindowAngledegDropDown.Items = {'0', '25', '22', '20', '17', '15', '12.5', '10', '7.5', '5', '2.5'};
            app.WindowAngledegDropDown.ItemsData = {'0', '25', '22', '20', '17', '15', '12.5', '10', '7.5', '5', '2.5'};
            app.WindowAngledegDropDown.ValueChangedFcn = createCallbackFcn(app, @WindowAngledegDropDownValueChanged, true);
            app.WindowAngledegDropDown.Value = '0';
            app.DotVelocityLabel = uilabel(app.RDKStimulusPanel);
            app.DotVelocityLabel.Text = 'Dot Velocity (deg/sec)';
            app.DotVelocitydegsecEditField = uieditfield(app.RDKStimulusPanel, 'numeric');
            app.DotVelocitydegsecEditField.ValueChangedFcn = createCallbackFcn(app, @DotVelocitydegsecEditFieldValueChanged, true);
            app.RIGHTButton = uibutton(app.RDKStimulusPanel, 'state');
            app.RIGHTButton.ValueChangedFcn = createCallbackFcn(app, @RIGHTButtonValueChanged, true);
            app.RIGHTButton.Text = 'RIGHT';
            app.UPButton = uibutton(app.RDKStimulusPanel, 'state');
            app.UPButton.ValueChangedFcn = createCallbackFcn(app, @UPButtonValueChanged, true);
            app.UPButton.Text = 'UP';
            app.LEFTButton = uibutton(app.RDKStimulusPanel, 'state');
            app.LEFTButton.ValueChangedFcn = createCallbackFcn(app, @LEFTButtonValueChanged, true);
            app.LEFTButton.Text = 'LEFT';
            app.DOWNButton = uibutton(app.RDKStimulusPanel, 'state');
            app.DOWNButton.ValueChangedFcn = createCallbackFcn(app, @DOWNButtonValueChanged, true);
            app.DOWNButton.Text = 'DOWN';
            app.DirectionsofMotiontoIncludeonlyLRworkLabel = uilabel(app.RDKStimulusPanel);
            app.DirectionsofMotiontoIncludeonlyLRworkLabel.Text = {'Directions of Motion to '; 'Include (only L & R work)'};
            app.ExperimentParametersPanel = uipanel(app.UIFigure);
            app.ExperimentParametersPanel.Title = 'Experiment Parameters';
            app.RDKStimulusTimemsEditFieldLabel = uilabel(app.ExperimentParametersPanel);
            app.RDKStimulusTimemsEditFieldLabel.Text = 'RDK  Stimulus Time (ms)';
            app.RDKStimulusTimemsEditField = uieditfield(app.ExperimentParametersPanel, 'numeric');
            app.RDKStimulusTimemsEditField.ValueChangedFcn = createCallbackFcn(app, @RDKStimulusTimemsEditFieldValueChanged, true);
            app.FixationPointOnlyTimemsEditFieldLabel = uilabel(app.ExperimentParametersPanel);
            app.FixationPointOnlyTimemsEditFieldLabel.Text = 'Fixation Point Only Time (ms)';
            app.FixationPointOnlyTimemsEditField = uieditfield(app.ExperimentParametersPanel, 'numeric');
            app.FixationPointOnlyTimemsEditField.ValueChangedFcn = createCallbackFcn(app, @FixationPointOnlyTimemsEditFieldValueChanged, true);
            app.DecisionTargetFixationTimemsEditFieldLabel = uilabel(app.ExperimentParametersPanel);
            app.DecisionTargetFixationTimemsEditFieldLabel.Text = 'Decision Target FixationTime (ms)';
            app.DecisionTargetFixationTimemsEditField = uieditfield(app.ExperimentParametersPanel, 'text');
            app.DecisionTargetFixationTimemsEditField.ValueChangedFcn = createCallbackFcn(app, @DecisionTargetFixationTimemsEditFieldValueChanged, true);
            app.DecisionTargetFixationTimemsEditField.Value = '0';
            app.IntertrialIntervalmsEditFieldLabel = uilabel(app.ExperimentParametersPanel);
            app.IntertrialIntervalmsEditFieldLabel.Text = 'Intertrial Interval (ms)';
            app.IntertrialIntervalmsEditField = uieditfield(app.ExperimentParametersPanel, 'numeric');
            app.IntertrialIntervalmsEditField.ValueChangedFcn = createCallbackFcn(app, @IntertrialIntervalmsEditFieldValueChanged, true);
            app.FailureTimeoutmsEditFieldLabel = uilabel(app.ExperimentParametersPanel);
            app.FailureTimeoutmsEditFieldLabel.Text = 'Failure Timeout (ms)';
            app.FailureTimeoutmsEditField = uieditfield(app.ExperimentParametersPanel, 'text');
            app.FailureTimeoutmsEditField.ValueChangedFcn = createCallbackFcn(app, @FailureTimeoutmsEditFieldValueChanged, true);
            app.FailureTimeoutmsEditField.Value = '0';
            app.TotalTrialsEditFieldLabel = uilabel(app.ExperimentParametersPanel);
            app.TotalTrialsEditFieldLabel.Text = 'Total Trials';
            app.TotalTrialsEditField = uieditfield(app.ExperimentParametersPanel, 'numeric');
            app.TotalTrialsEditField.ValueChangedFcn = createCallbackFcn(app, @TotalTrialsEditFieldValueChanged, true);
            app.CatchTrialsEditFieldLabel = uilabel(app.ExperimentParametersPanel);
            app.CatchTrialsEditFieldLabel.Text = '% Catch Trials';
            app.CatchTrialsEditField = uieditfield(app.ExperimentParametersPanel, 'numeric');
            app.CatchTrialsEditField.ValueChangedFcn = createCallbackFcn(app, @CatchTrialsEditFieldValueChanged, true);
            app.CentralPositionsforFixationPanel = uipanel(app.UIFigure);
            app.CentralPositionsforFixationPanel.Title = 'Central Positions for Fixation ';
            app.Button_1 = uibutton(app.CentralPositionsforFixationPanel, 'state');
            app.Button_1.ValueChangedFcn = createCallbackFcn(app, @Button_1_1ValueChanged, true);
            app.Button_1.Text = '1';
            app.Button_2 = uibutton(app.CentralPositionsforFixationPanel, 'state');
            app.Button_2.ValueChangedFcn = createCallbackFcn(app, @Button_2ValueChanged, true);
            app.Button_2.Text = '2';
            app.Button_3 = uibutton(app.CentralPositionsforFixationPanel, 'state');
            app.Button_3.ValueChangedFcn = createCallbackFcn(app, @Button_3ValueChanged, true);
            app.Button_3.Text = '3';
            app.Button_4 = uibutton(app.CentralPositionsforFixationPanel, 'state');
            app.Button_4.ValueChangedFcn = createCallbackFcn(app, @Button_4ValueChanged, true);
            app.Button_4.Text = '4';
            app.Button_5 = uibutton(app.CentralPositionsforFixationPanel, 'state');
            app.Button_5.ValueChangedFcn = createCallbackFcn(app, @Button_5ValueChanged, true);
            app.Button_5.Text = '5';
            app.Button_6 = uibutton(app.CentralPositionsforFixationPanel, 'state');
            app.Button_6.ValueChangedFcn = createCallbackFcn(app, @Button_6ValueChanged, true);
            app.Button_6.Text = '6';
            app.Button_7 = uibutton(app.CentralPositionsforFixationPanel, 'state');
            app.Button_7.ValueChangedFcn = createCallbackFcn(app, @Button_7ValueChanged, true);
            app.Button_7.Text = '7';
            app.Button_8 = uibutton(app.CentralPositionsforFixationPanel, 'state');
            app.Button_8.ValueChangedFcn = createCallbackFcn(app, @Button_8ValueChanged, true);
            app.Button_8.Text = '8';
            app.Button_9 = uibutton(app.CentralPositionsforFixationPanel, 'state');
            app.Button_9.ValueChangedFcn = createCallbackFcn(app, @Button_9ValueChanged, true);
            app.Button_9.Text = '9';
            app.FixationStimulusPanel = uipanel(app.UIFigure);
            app.FixationStimulusPanel.Title = 'Fixation Stimulus';
            app.TargetAngledegDropDownLabel = uilabel(app.FixationStimulusPanel);
            app.TargetAngledegDropDownLabel.Text = 'Target Angle (deg)';
            app.TargetAngledegDropDown = uidropdown(app.FixationStimulusPanel);
            app.TargetAngledegDropDown.Items = {'0', '.1', '.2', '.3', '.4', '.5', '.6', '.7', '.8', '.9', '1', '1.1', '1.2', '1.3', '1.4', '1.5', '1.6', '1.7', ''};
            app.TargetAngledegDropDown.ItemsData = {'0', '.1', '.2', '.3', '.4', '.5', '.6', '.7', '.8', '.9', '1', '1.1', '1.2', '1.3', '1.4', '1.5', '1.6', '1.7', ''};
            app.TargetAngledegDropDown.ValueChangedFcn = createCallbackFcn(app, @TargetAngledegDropDownValueChanged, true);
            app.TargetAngledegDropDown.Value = '0';
            app.RewardAngledegDropDownLabel = uilabel(app.FixationStimulusPanel);
            app.RewardAngledegDropDownLabel.Text = 'Reward Angle (deg)';
            app.RewardAngledegDropDown = uidropdown(app.FixationStimulusPanel);
            app.RewardAngledegDropDown.Items = {'0', '30', '27', '26', '25', '23', '22', '20', '17', '15', '14', '13', '12.5', '10', '7.5', '5', '2.5'};
            app.RewardAngledegDropDown.ItemsData = {'0', '30', '27', '26', '25', '23', '22', '20', '17', '15', '12.5', '10', '7.5', '5', '2.5'};
            app.RewardAngledegDropDown.ValueChangedFcn = createCallbackFcn(app, @RewardAngledegDropDownValueChanged, true);
            app.RewardAngledegDropDown.Value = '0';
            app.StimulusCoherenceRegularTrialsPanel = uipanel(app.UIFigure);
            app.StimulusCoherenceRegularTrialsPanel.Title = 'Stimulus Coherence (RegularTrials)';
            app.ProbabilitiesPanel = uipanel(app.StimulusCoherenceRegularTrialsPanel);
            app.ProbabilitiesPanel.Title = 'Probabilities';
            app.aEditFieldLabel = uilabel(app.ProbabilitiesPanel);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.ProbabilitiesPanel, 'numeric');
            app.aEditField.ValueChangedFcn = createCallbackFcn(app, @aEditFieldValueChanged, true);
            app.bEditFieldLabel = uilabel(app.ProbabilitiesPanel);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.ProbabilitiesPanel, 'numeric');
            app.bEditField.ValueChangedFcn = createCallbackFcn(app, @bEditFieldValueChanged, true);
            app.cEditFieldLabel = uilabel(app.ProbabilitiesPanel);
            app.cEditFieldLabel.Text = 'c';
            app.cEditField = uieditfield(app.ProbabilitiesPanel, 'numeric');
            app.cEditField.ValueChangedFcn = createCallbackFcn(app, @cEditFieldValueChanged, true);
            app.dEditFieldLabel = uilabel(app.ProbabilitiesPanel);
            app.dEditFieldLabel.Text = 'd';
            app.dEditField = uieditfield(app.ProbabilitiesPanel, 'numeric');
            app.dEditField.ValueChangedFcn = createCallbackFcn(app, @dEditFieldValueChanged, true);
            app.eEditFieldLabel = uilabel(app.ProbabilitiesPanel);
            app.eEditFieldLabel.Text = 'e';
            app.eEditField = uieditfield(app.ProbabilitiesPanel, 'numeric');
            app.eEditField.ValueChangedFcn = createCallbackFcn(app, @eEditFieldValueChanged, true);
            app.fEditFieldLabel = uilabel(app.ProbabilitiesPanel);
            app.fEditFieldLabel.Text = 'f';
            app.fEditField = uieditfield(app.ProbabilitiesPanel, 'numeric');
            app.fEditField.ValueChangedFcn = createCallbackFcn(app, @fEditFieldValueChanged, true);
            app.CoherencesAVconditionusestheseListsPanel = uipanel(app.StimulusCoherenceRegularTrialsPanel);
            app.CoherencesAVconditionusestheseListsPanel.Title = 'Coherences (AV condition uses these Lists!!)';
            app.AUDCoherencesEditFieldLabel = uilabel(app.CoherencesAVconditionusestheseListsPanel);
            app.AUDCoherencesEditFieldLabel.Text = 'AUD Coherences = ';
            app.AUDCoherencesEditField = uieditfield(app.CoherencesAVconditionusestheseListsPanel, 'text');
            app.AUDCoherencesEditField.ValueChangedFcn = createCallbackFcn(app, @AUDCoherencesEditFieldValueChanged, true);
            app.EnterCohswithspaceinbetweeneachex100908050MaxofCohs13Label = uilabel(app.CoherencesAVconditionusestheseListsPanel);
            app.EnterCohswithspaceinbetweeneachex100908050MaxofCohs13Label.Text = {'Enter Coh''s in descending order '; 'with space in between each'; 'ex. 100 90 80 50'; 'Max # of Coh''s in each = 11'};
            app.VISCoherencesEditFieldLabel = uilabel(app.CoherencesAVconditionusestheseListsPanel);
            app.VISCoherencesEditFieldLabel.Text = 'VIS Coherences = ';
            app.VISCoherencesEditField = uieditfield(app.CoherencesAVconditionusestheseListsPanel, 'text');
            app.VISCoherencesEditField.ValueChangedFcn = createCallbackFcn(app, @VISCoherencesEditFieldValueChanged, true);
            app.CloseFigureButton = uibutton(app.UIFigure, 'push');
            app.CloseFigureButton.ButtonPushedFcn = createCallbackFcn(app, @CloseFigureButtonPushed, true);
            app.CloseFigureButton.Text = 'Close Figure';
            app.AuditoryStimulusContPanel = uipanel(app.UIFigure);
            app.AuditoryStimulusContPanel.Title = 'Auditory Stimulus Cont. ';
            app.TotalAUDStimTimemsTextAreaLabel = uilabel(app.AuditoryStimulusContPanel);
            app.TotalAUDStimTimemsTextAreaLabel.Text = {'Total AUD Stim'; 'Time (ms)'};
            app.TotalAUDStimTimemsTextArea = uitextarea(app.AuditoryStimulusContPanel);
            app.AUDStarttoEndTImesmustnotbelongerthan2500msLabel = uilabel(app.AuditoryStimulusContPanel);
            app.AUDStarttoEndTImesmustnotbelongerthan2500msLabel.Text = {'AUD Start to End Times must '; 'be within values of 0 to set_dur, and total time must not '; 'exceed set_dur ms'};
            app.set_durtimetotalavailmsTextAreaLabel = uilabel(app.AuditoryStimulusContPanel);
            app.set_durtimetotalavailmsTextAreaLabel.Text = {'set_dur time  '; '(total avail. ms)'};
            app.set_durtimetotalavailmsTextArea = uitextarea(app.AuditoryStimulusContPanel);
            app.AuditoryStimulusPanel = uipanel(app.UIFigure);
            app.AuditoryStimulusPanel.Title = 'Auditory Stimulus ';
            app.VelocitydegsecEditFieldLabel = uilabel(app.AuditoryStimulusPanel);
            app.VelocitydegsecEditFieldLabel.Text = 'Velocity (deg/sec)';
            app.VelocitydegsecEditField = uieditfield(app.AuditoryStimulusPanel, 'numeric');
            app.VelocitydegsecEditField.ValueChangedFcn = createCallbackFcn(app, @VelocitydegsecEditFieldValueChanged, true);
            app.LtoRCheckBox = uicheckbox(app.AuditoryStimulusPanel);
            app.LtoRCheckBox.Text = 'L to R';
            app.RtoLCheckBox = uicheckbox(app.AuditoryStimulusPanel);
            app.RtoLCheckBox.Text = 'R to L';
            app.UtoDCheckBox = uicheckbox(app.AuditoryStimulusPanel);
            app.UtoDCheckBox.Text = 'U to D';
            app.DtoUCheckBox = uicheckbox(app.AuditoryStimulusPanel);
            app.DtoUCheckBox.Text = 'D to U';
            app.AUDStartTimemsEditFieldLabel = uilabel(app.AuditoryStimulusPanel);
            app.AUDStartTimemsEditFieldLabel.Text = 'AUD Start Time (ms)';
            app.AUDStartTimemsEditField = uieditfield(app.AuditoryStimulusPanel, 'numeric');
            app.AUDStartTimemsEditField.ValueChangedFcn = createCallbackFcn(app, @AUDStartTimemsEditFieldValueChanged, true);
            app.AUDEndTimemsEditFieldLabel = uilabel(app.AuditoryStimulusPanel);
            app.AUDEndTimemsEditFieldLabel.Text = 'AUD End Time (ms)';
            app.AUDEndTimemsEditField = uieditfield(app.AuditoryStimulusPanel, 'numeric');
            app.AUDEndTimemsEditField.ValueChangedFcn = createCallbackFcn(app, @AUDEndTimemsEditFieldValueChanged, true);
            app.CalcTotalAUDStimTImeButton = uibutton(app.AuditoryStimulusPanel, 'push');
            app.CalcTotalAUDStimTImeButton.ButtonPushedFcn = createCallbackFcn(app, @CalcTotalAUDStimTImeButtonPushed2, true);
            app.CalcTotalAUDStimTImeButton.Text = 'Calc. Total AUD Stim TIme';
            app.CalcTotalset_durTimeButton = uibutton(app.AuditoryStimulusPanel, 'push');
            app.CalcTotalset_durTimeButton.ButtonPushedFcn = createCallbackFcn(app, @CalcTotalset_durTimeButtonPushed, true);
            app.CalcTotalset_durTimeButton.Text = 'Calc. Total set_dur Time';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Experiment_Parameters

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end