classdef GUI_Test < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TargetAngledegDropDownLabel  matlab.ui.control.Label
        TargetAngledegDropDown       matlab.ui.control.DropDown
        WindowAngledegDropDownLabel  matlab.ui.control.Label
        WindowAngledegDropDown       matlab.ui.control.DropDown
        DotPositionButtonGroup       matlab.ui.container.ButtonGroup
        CL                           matlab.ui.control.RadioButton
        TR                           matlab.ui.control.RadioButton
        TC                           matlab.ui.control.RadioButton
        TL                           matlab.ui.control.RadioButton
        C                            matlab.ui.control.RadioButton
        CR                           matlab.ui.control.RadioButton
        BL                           matlab.ui.control.RadioButton
        BC                           matlab.ui.control.RadioButton
        BR                           matlab.ui.control.RadioButton
        ApplyParametersButton        matlab.ui.control.Button
    end

    
    properties (Access = private)
        data_vec = zeros(1,3) ; % Vector for all of GUI data input to be written to .txt file
    end
 

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: TargetAngledegDropDown
        function TargetAngledegDropDownValueChanged(app, event)
            t_angle = app.TargetAngledegDropDown.Value;
            t_angle = str2double(t_angle);
            app.data_vec(:,1) = t_angle; 
        end

        % Value changed function: WindowAngledegDropDown
        function WindowAngledegDropDownValueChanged(app, event)
            w_angle = app.WindowAngledegDropDown.Value;
            w_angle = str2double(w_angle);
            app.data_vec(:,2) = w_angle;
        end

        % Selection changed function: DotPositionButtonGroup
        function DotPositionButtonGroupSelectionChanged(app, event)

           if app.CL.Value
               pos_val = 4;
           elseif app.TR.Value
               pos_val = 3;
           elseif app.TC.Value
               pos_val = 2;
           elseif app.TL.Value
               pos_val = 1;
           elseif app.C.Value
               pos_val = 5;
           elseif app.CR.Value
               pos_val = 6;
           elseif app.BL.Value
               pos_val = 7;
           elseif app.BC.Value
               pos_val = 8;
           elseif app.BR.Value
               pos_val = 9;
           end
           
           app.data_vec(:,3) = pos_val; 
        end

        % Button pushed function: ApplyParametersButton
        function ApplyParametersButtonPushed(app, event)
           
            f = fopen("data_file.txt","w");
            fprintf(f,'%d ',app.data_vec);
            fclose(f);
            close(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TargetAngledegDropDownLabel = uilabel(app.UIFigure);
            app.TargetAngledegDropDownLabel.Text = 'Target Angle (deg)';
            app.TargetAngledegDropDown = uidropdown(app.UIFigure);
            app.TargetAngledegDropDown.Items = {'0', '.1', '.2', '.3', '.4', '.5', '.6', '.7', '.8', '.9', '1', '1.1', '1.2', '1.3', '1.4', '1.5', '1.6', '1.7', ''};
            app.TargetAngledegDropDown.ItemsData = {'0', '.1', '.2', '.3', '.4', '.5', '.6', '.7', '.8', '.9', '1', '1.1', '1.2', '1.3', '1.4', '1.5', '1.6', '1.7', ''};
            app.TargetAngledegDropDown.ValueChangedFcn = createCallbackFcn(app, @TargetAngledegDropDownValueChanged, true);
            app.TargetAngledegDropDown.Value = '0';
            app.WindowAngledegDropDownLabel = uilabel(app.UIFigure);
            app.WindowAngledegDropDownLabel.Text = 'Window Angle (deg)';
            app.WindowAngledegDropDown = uidropdown(app.UIFigure);
            app.WindowAngledegDropDown.Items = {'0', '15', '12.5', '10', '7.5', '5', '2.5'};
            app.WindowAngledegDropDown.ItemsData = {'0', '15', '12.5', '10', '7.5', '5', '2.5'};
            app.WindowAngledegDropDown.ValueChangedFcn = createCallbackFcn(app, @WindowAngledegDropDownValueChanged, true);
            app.WindowAngledegDropDown.Value = '0';
            app.DotPositionButtonGroup = uibuttongroup(app.UIFigure);
            app.DotPositionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DotPositionButtonGroupSelectionChanged, true);
            app.DotPositionButtonGroup.Title = 'Dot Position';
            app.CL = uiradiobutton(app.DotPositionButtonGroup);
            app.CL.Text = '4';
            app.TR = uiradiobutton(app.DotPositionButtonGroup);
            app.TR.Text = '3';
            app.TC = uiradiobutton(app.DotPositionButtonGroup);
            app.TC.Text = '2';
            app.TL = uiradiobutton(app.DotPositionButtonGroup);
            app.TL.Text = '1';
            app.C = uiradiobutton(app.DotPositionButtonGroup);
            app.C.Text = '5';
            app.CR = uiradiobutton(app.DotPositionButtonGroup);
            app.CR.Text = '6';
            app.BL = uiradiobutton(app.DotPositionButtonGroup);
            app.BL.Text = '7';
            app.BL.Value = true;
            app.BC = uiradiobutton(app.DotPositionButtonGroup);
            app.BC.Text = '8';
            app.BR = uiradiobutton(app.DotPositionButtonGroup);
            app.BR.Text = '9';
            app.ApplyParametersButton = uibutton(app.UIFigure, 'push');
            app.ApplyParametersButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyParametersButtonPushed, true);
            app.ApplyParametersButton.Text = 'Apply Parameters';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_Test

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end